classdef cal_capture < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        bkTab                       matlab.ui.container.Tab
        UIAxes_2                    matlab.ui.control.UIAxes
        zstackTab                   matlab.ui.container.Tab
        FrameSlider                 matlab.ui.control.Slider
        FrameSliderLabel            matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        OutlierEditField            matlab.ui.control.NumericEditField
        OutlierEditFieldLabel       matlab.ui.control.Label
        CapturezscanButton          matlab.ui.control.Button
        CapturebkButton             matlab.ui.control.Button
        NavgEditField               matlab.ui.control.NumericEditField
        NavgEditFieldLabel          matlab.ui.control.Label
        NsampleEditField            matlab.ui.control.NumericEditField
        NsampleEditFieldLabel       matlab.ui.control.Label
        FullzrangemmEditField       matlab.ui.control.NumericEditField
        FullzrangemmEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp
        data_path
        im_data
        h
        h_title
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, cal_path)
            app.MainApp = mainapp;
            addpath('utils\')
            app.data_path = cal_path;
        end

        % Button pushed function: CapturebkButton
        function CapturebkButtonPushed(app, event)
            %% pause buffer
            pause_update(app.MainApp);
            %% capture
            N_avg = app.NavgEditField.Value;
            bk = mean(captureN(app.MainApp, N_avg),3);
            imagesc(app.UIAxes_2,bk'); axis(app.UIAxes_2,'off')
            app.TabGroup.SelectedTab = app.bkTab;
            %% save
            % filename = [datestr(now, 'yymmdd'),'_bk_1.mat'];
            filename = 'bk.mat';
            [filename, pathname] = uiputfile(fullfile(app.data_path,filename),...
                'Save bk file:');
            if isequal(filename,0) || isequal(pathname,0)
                return;
            end
            app.data_path = pathname;
            FileName = fullfile(pathname, filename);
            save(FileName,'bk');
            % Call main app's public function
            updateParams3(app.MainApp, pathname);
            %% resume buffer
            resume_update(app.MainApp);
        end

        % Button pushed function: CapturezscanButton
        function CapturezscanButtonPushed(app, event)
            app.FrameSlider.Enable = false;
            %% pause buffer
            pause_update(app.MainApp);
            %% capture
            N = app.NsampleEditField.Value;
            N_avg = app.NavgEditField.Value;
            z_range = app.FullzrangemmEditField.Value;
            z = System.Decimal.ToDouble(app.MainApp.device.Position);
            z_list = z + linspace(-z_range/2, z_range/2, N);
            % prepare
            timeout_val=60000;
            imgcube=zeros(app.MainApp.width,app.MainApp.height,N);
            % reset z stage
            app.MainApp.device.MoveTo(z_list(1), timeout_val);
            pause(1);
            app.TabGroup.SelectedTab = app.zstackTab;
            % start loop
            for i=1:N
                % z stage move
                app.MainApp.device.MoveTo(z_list(i),timeout_val);
                pause(0.1);
                % capture mean of N frames
                imgcube(:,:,i) = mean(captureN(app.MainApp, N_avg), 3);
                % bad pixel correction
                imgcube(:,:,i) = bad_pixel_correction(app.MainApp,imgcube(:,:,i));
                % update drawing
                app.h = imagesc(app.UIAxes,imgcube(:,:,i)'); axis(app.UIAxes,'off')
                colormap(app.UIAxes,'gray')
                title(app.UIAxes,sprintf('Frame %d/%d', i, N))
                drawnow
                % update display
                update_z_pos(app.MainApp);
                refresh(app.MainApp)
            end
            % move back
            app.MainApp.device.MoveTo(z, timeout_val);
            update_z_pos(app.MainApp);
            % remove outliers
            out_ratio = app.OutlierEditField.Value;
            threshold_x = prctile(imgcube(:), out_ratio);
            imgcube(imgcube>threshold_x) = threshold_x;
            % update data and slice plot
            app.im_data = imgcube;
            app.FrameSlider.Value = 1;
            app.FrameSlider.Limits = [1,size(imgcube,3)];
            app.h = imagesc(app.UIAxes,imgcube(:,:,1)'); axis(app.UIAxes,'off');
            app.h_title = title(app.UIAxes,sprintf('Frame %d/%d', 1, N));
            colormap(app.UIAxes,'gray')
            app.FrameSlider.Enable = true;
            %% save
            % filename = [datestr(now, 'yymmdd'),'_cal_1.mat'];
            filename = 'cal.mat';
            [filename, pathname] = uiputfile(fullfile(app.data_path,filename),...
                'Save calibration file:');
            if isequal(filename,0) || isequal(pathname,0)
                return;
            end
            app.data_path = pathname;
            FileName = fullfile(pathname, filename);
            save(FileName,'imgcube');
            % Call main app's public function
            updateParams3(app.MainApp, pathname);
            %% resume buffer
            resume_update(app.MainApp);
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = round(app.FrameSlider.Value);
            set(app.h,'CData',app.im_data(:,:,value)')
            app.h_title.String = sprintf('Frame %d/%d', value, size(app.im_data,3));
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            value = round(event.Value);
            set(app.h,'CData',app.im_data(:,:,value)')
            app.h_title.String = sprintf('Frame %d/%d', value, size(app.im_data,3));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainApp.CaptureCalibButton.Enable = "on";
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FullzrangemmEditFieldLabel = uilabel(app.UIFigure);
            app.FullzrangemmEditFieldLabel.Text = 'Full z range (mm)';
            app.FullzrangemmEditField = uieditfield(app.UIFigure, 'numeric');
            app.FullzrangemmEditField.ValueDisplayFormat = '%11.5g';
            app.FullzrangemmEditField.Value = 0.6;
            app.NsampleEditFieldLabel = uilabel(app.UIFigure);
            app.NsampleEditFieldLabel.Text = 'N sample';
            app.NsampleEditField = uieditfield(app.UIFigure, 'numeric');
            app.NsampleEditField.RoundFractionalValues = 'on';
            app.NsampleEditField.Value = 21;
            app.NavgEditFieldLabel = uilabel(app.UIFigure);
            app.NavgEditFieldLabel.Text = 'N avg.';
            app.NavgEditField = uieditfield(app.UIFigure, 'numeric');
            app.NavgEditField.RoundFractionalValues = 'on';
            app.NavgEditField.Value = 100;
            app.CapturebkButton = uibutton(app.UIFigure, 'push');
            app.CapturebkButton.ButtonPushedFcn = createCallbackFcn(app, @CapturebkButtonPushed, true);
            app.CapturebkButton.Text = 'Capture bk';
            app.CapturezscanButton = uibutton(app.UIFigure, 'push');
            app.CapturezscanButton.ButtonPushedFcn = createCallbackFcn(app, @CapturezscanButtonPushed, true);
            app.CapturezscanButton.Text = 'Capture z-scan';
            app.OutlierEditFieldLabel = uilabel(app.UIFigure);
            app.OutlierEditFieldLabel.Text = 'Outlier %';
            app.OutlierEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutlierEditField.RoundFractionalValues = 'on';
            app.OutlierEditField.ValueDisplayFormat = '%11.7g';
            app.OutlierEditField.Value = 100;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.bkTab = uitab(app.TabGroup);
            app.bkTab.Title = 'bk';
            app.UIAxes_2 = uiaxes(app.bkTab);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.zstackTab = uitab(app.TabGroup);
            app.zstackTab.Title = 'zstack';
            app.UIAxes = uiaxes(app.zstackTab);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.FrameSliderLabel = uilabel(app.zstackTab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.zstackTab);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cal_capture(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end