classdef cal_dark < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FinaladjustPanel              matlab.ui.container.Panel
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        ClipSlider                    matlab.ui.control.RangeSlider
        ClipSliderLabel               matlab.ui.control.Label
        ifClipButton                  matlab.ui.control.StateButton
        BadpixelcorrectionPanel       matlab.ui.container.Panel
        MaxSizeEditField              matlab.ui.control.NumericEditField
        MaxSizeEditFieldLabel         matlab.ui.control.Label
        WinSizeEditField              matlab.ui.control.NumericEditField
        WinSizeEditFieldLabel         matlab.ui.control.Label
        ifBadButton                   matlab.ui.control.StateButton
        zScoreEditField               matlab.ui.control.NumericEditField
        zScoreEditFieldLabel          matlab.ui.control.Label
        DarkPanel                     matlab.ui.container.Panel
        ifDarkButton                  matlab.ui.control.StateButton
        PixelsizeYumEditField         matlab.ui.control.NumericEditField
        PixelsizeYumEditFieldLabel    matlab.ui.control.Label
        strictremovingsevereCheckBox  matlab.ui.control.CheckBox
        WavelengthnmEditField         matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel    matlab.ui.control.Label
        FrequencydivisioncoefficientEditField  matlab.ui.control.NumericEditField
        FrequencydivisioncoefficientEditFieldLabel  matlab.ui.control.Label
        LowfrequencydehazingthresholdEditField  matlab.ui.control.NumericEditField
        LowfrequencydehazingthresholdEditFieldLabel  matlab.ui.control.Label
        GaussiandenoiseCheckBox       matlab.ui.control.CheckBox
        PixelsizeXumEditField         matlab.ui.control.NumericEditField
        PixelsizeXumEditFieldLabel    matlab.ui.control.Label
        NAEditField                   matlab.ui.control.NumericEditField
        NAEditFieldLabel              matlab.ui.control.Label
        TophatRollingballPanel        matlab.ui.container.Panel
        rollingballCheckBox           matlab.ui.control.CheckBox
        ifTophatButton                matlab.ui.control.StateButton
        StrelsizeEditField            matlab.ui.control.NumericEditField
        StrelsizeEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            % switch back to main app

            % DARK/TOPHAT
            app.StrelsizeEditField.Value = mainapp.dark_param.strel_size;
            app.WavelengthnmEditField.Value = mainapp.dark_param.Wavelength;
            app.PixelsizeXumEditField.Value  = mainapp.dark_param.PixelSizeX / 1000;
            app.PixelsizeYumEditField.Value = mainapp.dark_param.PixelSizeY / 1000;
            app.NAEditField.Value = mainapp.dark_param.NA;
            app.FrequencydivisioncoefficientEditField.Value = mainapp.dark_param.DivideCoeff;
            app.LowfrequencydehazingthresholdEditField.Value = mainapp.dark_param.Threshold;
            app.GaussiandenoiseCheckBox.Value = mainapp.dark_param.Denoise;
            app.strictremovingsevereCheckBox.Value = mainapp.dark_param.BackgroundMode;
            
            % HOT PIXEL
            app.zScoreEditField.Value = mainapp.dark_param.zScore;
            app.WinSizeEditField.Value = mainapp.dark_param.WinSize;
            app.MaxSizeEditField.Value = mainapp.dark_param.MaxSize;
        
            % FINAL
            app.ClipSlider.Value = mainapp.dark_param.clip;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % switch back to main app
            app.MainApp.dark_param.if_dark = app.ifDarkButton.Value;
            app.MainApp.dark_param.if_tophat = app.ifTophatButton.Value;
            app.MainApp.dark_param.tophat_roll = app.rollingballCheckBox.Value;

            app.MainApp.dark_param.strel_size = app.StrelsizeEditField.Value;
            app.MainApp.dark_param.Wavelength = app.WavelengthnmEditField.Value;
            app.MainApp.dark_param.PixelSizeX = app.PixelsizeXumEditField.Value * 1000;
            app.MainApp.dark_param.PixelSizeY = app.PixelsizeYumEditField.Value * 1000;
            app.MainApp.dark_param.NA = app.NAEditField.Value;
            app.MainApp.dark_param.DivideCoeff = app.FrequencydivisioncoefficientEditField.Value;
            app.MainApp.dark_param.Threshold = app.LowfrequencydehazingthresholdEditField.Value;
            app.MainApp.dark_param.Denoise = app.GaussiandenoiseCheckBox.Value;
            app.MainApp.dark_param.BackgroundMode = app.strictremovingsevereCheckBox.Value;

            % HOT PIXEL
            app.MainApp.dark_param.if_bad = app.ifBadButton.Value;
            app.MainApp.dark_param.zScore = app.zScoreEditField.Value;
            app.MainApp.dark_param.WinSize = app.WinSizeEditField.Value;
            app.MainApp.dark_param.MaxSize = app.MaxSizeEditField.Value;
        
            % FINAL
            app.MainApp.dark_param.if_clip = app.ifClipButton.Value;
            app.MainApp.dark_param.clip = app.ClipSlider.Value;

            init_2D(app.MainApp);
            figure(app.MainApp.UIFigure); drawnow
            delete(app)
        end

        % Value changed function: StrelsizeEditField
        function StrelsizeEditFieldValueChanged(app, event)
            app.MainApp.dark_param.strel_size = app.StrelsizeEditField.Value;
            init_2D(app.MainApp)
        end

        % Value changed function: WavelengthnmEditField
        function WavelengthnmEditFieldValueChanged(app, event)
            app.MainApp.dark_param.Wavelength = app.WavelengthnmEditField.Value;
            init_2D(app.MainApp)
        end

        % Value changed function: PixelsizeXumEditField
        function PixelsizeXumEditFieldValueChanged(app, event)
            app.MainApp.dark_param.PixelSizeX = app.PixelsizeXumEditField.Value * 1000;
            init_2D(app.MainApp)
        end

        % Value changed function: NAEditField
        function NAEditFieldValueChanged(app, event)
            app.MainApp.dark_param.NA = app.NAEditField.Value;
            init_2D(app.MainApp)
        end

        % Value changed function: FrequencydivisioncoefficientEditField
        function FrequencydivisioncoefficientEditFieldValueChanged(app, event)
            app.MainApp.dark_param.DivideCoeff = app.FrequencydivisioncoefficientEditField.Value;
            init_2D(app.MainApp)
        end

        % Value changed function: LowfrequencydehazingthresholdEditField
        function LowfrequencydehazingthresholdEditFieldValueChanged(app, event)
            app.MainApp.dark_param.Threshold = app.LowfrequencydehazingthresholdEditField.Value;
            init_2D(app.MainApp)
        end

        % Value changed function: GaussiandenoiseCheckBox
        function GaussiandenoiseCheckBoxValueChanged(app, event)
            app.MainApp.dark_param.Denoise = app.GaussiandenoiseCheckBox.Value;
            init_2D(app.MainApp)
        end

        % Value changed function: strictremovingsevereCheckBox
        function strictremovingsevereCheckBoxValueChanged(app, event)
            app.MainApp.dark_param.BackgroundMode = app.strictremovingsevereCheckBox.Value;
            init_2D(app.MainApp)
        end

        % Value changed function: PixelsizeYumEditField
        function PixelsizeYumEditFieldValueChanged(app, event)
            app.MainApp.dark_param.PixelSizeY = app.PixelsizeYumEditField.Value * 1000;
            init_2D(app.MainApp)
        end

        % Value changed function: zScoreEditField
        function zScoreEditFieldValueChanged(app, event)
            app.MainApp.dark_param.zScore = app.zScoreEditField.Value;
            init_2D(app.MainApp)
        end

        % Value changed function: WinSizeEditField
        function WinSizeEditFieldValueChanged(app, event)
            app.MainApp.dark_param.WinSize = app.WinSizeEditField.Value;
            init_2D(app.MainApp)
        end

        % Value changed function: MaxSizeEditField
        function MaxSizeEditFieldValueChanged(app, event)
            app.MainApp.dark_param.MaxSize = app.MaxSizeEditField.Value;
            init_2D(app.MainApp)
        end

        % Value changed function: ifBadButton
        function ifBadButtonValueChanged(app, event)
            app.MainApp.dark_param.if_bad = app.ifBadButton.Value;
            init_2D(app.MainApp)
        end

        % Value changed function: ifDarkButton
        function ifDarkButtonValueChanged(app, event)
            app.MainApp.dark_param.if_dark = app.ifDarkButton.Value;
            init_2D(app.MainApp)
        end

        % Value changed function: ifTophatButton
        function ifTophatButtonValueChanged(app, event)
            app.MainApp.dark_param.if_tophat = app.ifTophatButton.Value;
            init_2D(app.MainApp)
        end

        % Value changed function: rollingballCheckBox
        function rollingballCheckBoxValueChanged(app, event)
            app.MainApp.dark_param.tophat_roll = app.rollingballCheckBox.Value;
            init_2D(app.MainApp)
        end

        % Value changed function: ClipSlider
        function ClipSliderValueChanged(app, event)
            app.MainApp.dark_param.clip = app.ClipSlider.Value;
            init_2D(app.MainApp)
        end

        % Value changed function: ifClipButton
        function ifClipButtonValueChanged(app, event)
            app.MainApp.dark_param.if_clip = app.ifClipButton.Value;
            init_2D(app.MainApp)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.ClipSlider.Value = app.ClipSlider.Limits/2;
            app.ClipSlider.Limits = app.ClipSlider.Limits/2;
            app.MainApp.dark_param.clip = app.ClipSlider.Value;
            init_2D(app.MainApp)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            val = min(1,app.ClipSlider.Limits(2)*2);
            app.ClipSlider.Limits = [0,val];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TophatRollingballPanel = uipanel(app.UIFigure);
            app.TophatRollingballPanel.Title = 'Tophat/Rolling ball';
            app.StrelsizeEditFieldLabel = uilabel(app.TophatRollingballPanel);
            app.StrelsizeEditFieldLabel.Text = 'Strel size';
            app.StrelsizeEditField = uieditfield(app.TophatRollingballPanel, 'numeric');
            app.StrelsizeEditField.ValueChangedFcn = createCallbackFcn(app, @StrelsizeEditFieldValueChanged, true);
            app.StrelsizeEditField.Value = 30;
            app.ifTophatButton = uibutton(app.TophatRollingballPanel, 'state');
            app.ifTophatButton.ValueChangedFcn = createCallbackFcn(app, @ifTophatButtonValueChanged, true);
            app.ifTophatButton.Text = 'On/off';
            app.rollingballCheckBox = uicheckbox(app.TophatRollingballPanel);
            app.rollingballCheckBox.ValueChangedFcn = createCallbackFcn(app, @rollingballCheckBoxValueChanged, true);
            app.rollingballCheckBox.Text = 'rolling ball';
            app.rollingballCheckBox.Value = true;
            app.DarkPanel = uipanel(app.UIFigure);
            app.DarkPanel.Title = 'Dark';
            app.NAEditFieldLabel = uilabel(app.DarkPanel);
            app.NAEditFieldLabel.Text = 'NA';
            app.NAEditField = uieditfield(app.DarkPanel, 'numeric');
            app.NAEditField.ValueChangedFcn = createCallbackFcn(app, @NAEditFieldValueChanged, true);
            app.NAEditField.Value = 0.16;
            app.PixelsizeXumEditFieldLabel = uilabel(app.DarkPanel);
            app.PixelsizeXumEditFieldLabel.Text = 'Pixel size X (um)';
            app.PixelsizeXumEditField = uieditfield(app.DarkPanel, 'numeric');
            app.PixelsizeXumEditField.ValueChangedFcn = createCallbackFcn(app, @PixelsizeXumEditFieldValueChanged, true);
            app.PixelsizeXumEditField.Value = 17;
            app.GaussiandenoiseCheckBox = uicheckbox(app.DarkPanel);
            app.GaussiandenoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @GaussiandenoiseCheckBoxValueChanged, true);
            app.GaussiandenoiseCheckBox.Text = 'Gaussian denoise';
            app.LowfrequencydehazingthresholdEditFieldLabel = uilabel(app.DarkPanel);
            app.LowfrequencydehazingthresholdEditFieldLabel.Text = 'Low frequency dehazing threshold';
            app.LowfrequencydehazingthresholdEditField = uieditfield(app.DarkPanel, 'numeric');
            app.LowfrequencydehazingthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @LowfrequencydehazingthresholdEditFieldValueChanged, true);
            app.LowfrequencydehazingthresholdEditField.Value = 70;
            app.FrequencydivisioncoefficientEditFieldLabel = uilabel(app.DarkPanel);
            app.FrequencydivisioncoefficientEditFieldLabel.Text = 'Frequency division coefficient';
            app.FrequencydivisioncoefficientEditField = uieditfield(app.DarkPanel, 'numeric');
            app.FrequencydivisioncoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencydivisioncoefficientEditFieldValueChanged, true);
            app.FrequencydivisioncoefficientEditField.Value = 0.5;
            app.WavelengthnmEditFieldLabel = uilabel(app.DarkPanel);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.DarkPanel, 'numeric');
            app.WavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthnmEditFieldValueChanged, true);
            app.WavelengthnmEditField.Value = 1100;
            app.strictremovingsevereCheckBox = uicheckbox(app.DarkPanel);
            app.strictremovingsevereCheckBox.ValueChangedFcn = createCallbackFcn(app, @strictremovingsevereCheckBoxValueChanged, true);
            app.strictremovingsevereCheckBox.Text = 'strict removing (severe)';
            app.strictremovingsevereCheckBox.Value = true;
            app.PixelsizeYumEditFieldLabel = uilabel(app.DarkPanel);
            app.PixelsizeYumEditFieldLabel.Text = 'Pixel size Y (um)';
            app.PixelsizeYumEditField = uieditfield(app.DarkPanel, 'numeric');
            app.PixelsizeYumEditField.ValueChangedFcn = createCallbackFcn(app, @PixelsizeYumEditFieldValueChanged, true);
            app.PixelsizeYumEditField.Value = 17;
            app.ifDarkButton = uibutton(app.DarkPanel, 'state');
            app.ifDarkButton.ValueChangedFcn = createCallbackFcn(app, @ifDarkButtonValueChanged, true);
            app.ifDarkButton.Text = 'On/off';
            app.BadpixelcorrectionPanel = uipanel(app.UIFigure);
            app.BadpixelcorrectionPanel.Title = 'Bad pixel correction';
            app.zScoreEditFieldLabel = uilabel(app.BadpixelcorrectionPanel);
            app.zScoreEditFieldLabel.Text = 'zScore';
            app.zScoreEditField = uieditfield(app.BadpixelcorrectionPanel, 'numeric');
            app.zScoreEditField.ValueChangedFcn = createCallbackFcn(app, @zScoreEditFieldValueChanged, true);
            app.zScoreEditField.Value = 2;
            app.ifBadButton = uibutton(app.BadpixelcorrectionPanel, 'state');
            app.ifBadButton.ValueChangedFcn = createCallbackFcn(app, @ifBadButtonValueChanged, true);
            app.ifBadButton.Text = 'On/off';
            app.ifBadButton.Value = true;
            app.WinSizeEditFieldLabel = uilabel(app.BadpixelcorrectionPanel);
            app.WinSizeEditFieldLabel.Text = 'WinSize';
            app.WinSizeEditField = uieditfield(app.BadpixelcorrectionPanel, 'numeric');
            app.WinSizeEditField.ValueChangedFcn = createCallbackFcn(app, @WinSizeEditFieldValueChanged, true);
            app.WinSizeEditField.Value = 3;
            app.MaxSizeEditFieldLabel = uilabel(app.BadpixelcorrectionPanel);
            app.MaxSizeEditFieldLabel.Text = 'MaxSize';
            app.MaxSizeEditField = uieditfield(app.BadpixelcorrectionPanel, 'numeric');
            app.MaxSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxSizeEditFieldValueChanged, true);
            app.MaxSizeEditField.Value = 3;
            app.FinaladjustPanel = uipanel(app.UIFigure);
            app.FinaladjustPanel.Title = 'Final adjust';
            app.ifClipButton = uibutton(app.FinaladjustPanel, 'state');
            app.ifClipButton.ValueChangedFcn = createCallbackFcn(app, @ifClipButtonValueChanged, true);
            app.ifClipButton.Text = 'On/off';
            app.ClipSliderLabel = uilabel(app.FinaladjustPanel);
            app.ClipSliderLabel.Text = 'Clip';
            app.ClipSlider = uislider(app.FinaladjustPanel, 'range');
            app.ClipSlider.Limits = [0 1];
            app.ClipSlider.ValueChangedFcn = createCallbackFcn(app, @ClipSliderValueChanged, true);
            app.ClipSlider.Step = 0.0001;
            app.ClipSlider.Value = [0 1];
            app.Button = uibutton(app.FinaladjustPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.FinaladjustPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cal_dark(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end