classdef cal_dark_3d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PixelsizeYumEditField         matlab.ui.control.NumericEditField
        PixelsizeYumEditFieldLabel    matlab.ui.control.Label
        WavelengthnmEditField         matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel    matlab.ui.control.Label
        FrequencydivisioncoefficientEditField  matlab.ui.control.NumericEditField
        FrequencydivisioncoefficientEditFieldLabel  matlab.ui.control.Label
        LowfrequencydehazingthresholdEditField  matlab.ui.control.NumericEditField
        LowfrequencydehazingthresholdEditFieldLabel  matlab.ui.control.Label
        PixelsizeXumEditField         matlab.ui.control.NumericEditField
        PixelsizeXumEditFieldLabel    matlab.ui.control.Label
        NAEditField                   matlab.ui.control.NumericEditField
        NAEditFieldLabel              matlab.ui.control.Label
        ifDarkButton                  matlab.ui.control.StateButton
        strictremovingsevereCheckBox  matlab.ui.control.CheckBox
        GaussiandenoiseCheckBox       matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        MainApp % Description
    end
    
    methods (Access = private)
        
        function update(app)
            app.MainApp.dark_param_3d.if_dark = app.ifDarkButton.Value;
            app.MainApp.dark_param_3d.Wavelength = app.WavelengthnmEditField.Value;
            app.MainApp.dark_param_3d.PixelSizeX = app.PixelsizeXumEditField.Value * 1000;
            app.MainApp.dark_param_3d.PixelSizeY = app.PixelsizeYumEditField.Value * 1000;
            app.MainApp.dark_param_3d.NA = app.NAEditField.Value;
            app.MainApp.dark_param_3d.DivideCoeff = app.FrequencydivisioncoefficientEditField.Value;
            app.MainApp.dark_param_3d.Threshold = app.LowfrequencydehazingthresholdEditField.Value;
            app.MainApp.dark_param_3d.Denoise = app.GaussiandenoiseCheckBox.Value;
            app.MainApp.dark_param_3d.BackgroundMode = app.strictremovingsevereCheckBox.Value;

            init_3D_simple(app.MainApp);   
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;

            app.WavelengthnmEditField.Value = mainapp.dark_param_3d.Wavelength;
            app.PixelsizeXumEditField.Value  = mainapp.dark_param_3d.PixelSizeX / 1000;
            app.PixelsizeYumEditField.Value = mainapp.dark_param_3d.PixelSizeY / 1000;
            app.NAEditField.Value = mainapp.dark_param_3d.NA;
            app.FrequencydivisioncoefficientEditField.Value = mainapp.dark_param_3d.DivideCoeff;
            app.LowfrequencydehazingthresholdEditField.Value = mainapp.dark_param_3d.Threshold;
            app.GaussiandenoiseCheckBox.Value = mainapp.dark_param_3d.Denoise;
            app.strictremovingsevereCheckBox.Value = mainapp.dark_param_3d.BackgroundMode;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            update(app);
            figure(app.MainApp.UIFigure); drawnow
            delete(app)
        end

        % Value changed function: strictremovingsevereCheckBox
        function strictremovingsevereCheckBoxValueChanged(app, event)
            update(app);
        end

        % Value changed function: GaussiandenoiseCheckBox
        function GaussiandenoiseCheckBoxValueChanged(app, event)
            update(app);
        end

        % Value changed function: WavelengthnmEditField
        function WavelengthnmEditFieldValueChanged(app, event)
            update(app);
        end

        % Value changed function: PixelsizeXumEditField
        function PixelsizeXumEditFieldValueChanged(app, event)
            update(app);
        end

        % Value changed function: PixelsizeYumEditField
        function PixelsizeYumEditFieldValueChanged(app, event)
            update(app);
        end

        % Value changed function: NAEditField
        function NAEditFieldValueChanged(app, event)
            update(app);
        end

        % Value changed function: FrequencydivisioncoefficientEditField
        function FrequencydivisioncoefficientEditFieldValueChanged(app, event)
            update(app);
        end

        % Value changed function: LowfrequencydehazingthresholdEditField
        function LowfrequencydehazingthresholdEditFieldValueChanged(app, event)
            update(app);
        end

        % Value changed function: ifDarkButton
        function ifDarkButtonValueChanged(app, event)
            update(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GaussiandenoiseCheckBox = uicheckbox(app.UIFigure);
            app.GaussiandenoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @GaussiandenoiseCheckBoxValueChanged, true);
            app.GaussiandenoiseCheckBox.Text = 'Gaussian denoise';
            app.strictremovingsevereCheckBox = uicheckbox(app.UIFigure);
            app.strictremovingsevereCheckBox.ValueChangedFcn = createCallbackFcn(app, @strictremovingsevereCheckBoxValueChanged, true);
            app.strictremovingsevereCheckBox.Text = 'strict removing (severe)';
            app.strictremovingsevereCheckBox.Value = true;
            app.ifDarkButton = uibutton(app.UIFigure, 'state');
            app.ifDarkButton.ValueChangedFcn = createCallbackFcn(app, @ifDarkButtonValueChanged, true);
            app.ifDarkButton.Text = 'On/off';
            app.NAEditFieldLabel = uilabel(app.UIFigure);
            app.NAEditFieldLabel.Text = 'NA';
            app.NAEditField = uieditfield(app.UIFigure, 'numeric');
            app.NAEditField.ValueChangedFcn = createCallbackFcn(app, @NAEditFieldValueChanged, true);
            app.NAEditField.Value = 0.16;
            app.PixelsizeXumEditFieldLabel = uilabel(app.UIFigure);
            app.PixelsizeXumEditFieldLabel.Text = 'Pixel size X (um)';
            app.PixelsizeXumEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelsizeXumEditField.ValueChangedFcn = createCallbackFcn(app, @PixelsizeXumEditFieldValueChanged, true);
            app.PixelsizeXumEditField.Value = 17;
            app.LowfrequencydehazingthresholdEditFieldLabel = uilabel(app.UIFigure);
            app.LowfrequencydehazingthresholdEditFieldLabel.Text = 'Low frequency dehazing threshold';
            app.LowfrequencydehazingthresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowfrequencydehazingthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @LowfrequencydehazingthresholdEditFieldValueChanged, true);
            app.LowfrequencydehazingthresholdEditField.Value = 70;
            app.FrequencydivisioncoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencydivisioncoefficientEditFieldLabel.Text = 'Frequency division coefficient';
            app.FrequencydivisioncoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencydivisioncoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencydivisioncoefficientEditFieldValueChanged, true);
            app.FrequencydivisioncoefficientEditField.Value = 0.5;
            app.WavelengthnmEditFieldLabel = uilabel(app.UIFigure);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthnmEditFieldValueChanged, true);
            app.WavelengthnmEditField.Value = 1100;
            app.PixelsizeYumEditFieldLabel = uilabel(app.UIFigure);
            app.PixelsizeYumEditFieldLabel.Text = 'Pixel size Y (um)';
            app.PixelsizeYumEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelsizeYumEditField.ValueChangedFcn = createCallbackFcn(app, @PixelsizeYumEditFieldValueChanged, true);
            app.PixelsizeYumEditField.Value = 17;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cal_dark_3d(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end