classdef cal_psf_estimation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        M_xM_yLabel                   matlab.ui.control.Label
        PCAorderlowrankSpinner        matlab.ui.control.Spinner
        PCAorderlowrankSpinnerLabel   matlab.ui.control.Label
        ReconSizeLabel                matlab.ui.control.Label
        PixelszLabel                  matlab.ui.control.Label
        UseGaussianfittedPSFCheckBox  matlab.ui.control.CheckBox
        PatchsizepixelEditField       matlab.ui.control.NumericEditField
        PatchsizepixelEditFieldLabel  matlab.ui.control.Label
        PSFEstimationnItersSpinner    matlab.ui.control.Spinner
        PSFEstimationnItersSpinnerLabel  matlab.ui.control.Label
        PatternDropDown               matlab.ui.control.DropDown
        PatternDropDownLabel          matlab.ui.control.Label
        RowsSpinner                   matlab.ui.control.Spinner
        Panel_2                       matlab.ui.container.Panel
        DepthLabel_2                  matlab.ui.control.Label
        ViewLabel_2                   matlab.ui.control.Label
        Label1_5                      matlab.ui.control.Label
        Button_right_5                matlab.ui.control.Button
        Button_left_5                 matlab.ui.control.Button
        Label1_2                      matlab.ui.control.Label
        Button_right_2                matlab.ui.control.Button
        Button_left_2                 matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        RowsSpinnerLabel              matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        Label1                        matlab.ui.control.Label
        Button_right                  matlab.ui.control.Button
        Button_left                   matlab.ui.control.Button
        SaveExitButton                matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        ParamTab                      matlab.ui.container.Tab
        ShowLowrankapproxButton       matlab.ui.control.Button
        ShowPSFssensorcoordButton     matlab.ui.control.Button
        ShowPSFsreconcoordButton      matlab.ui.control.Button
        ShowWarped3DPSFButton         matlab.ui.control.Button
        ViewDropDown                  matlab.ui.control.DropDown
        ViewDropDownLabel             matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        ReExtractPSFsButton           matlab.ui.control.Button
        StartcentroidsfittingButton   matlab.ui.control.Button
        TemplatematchingforcentralframeButton  matlab.ui.control.Button
        ImportRegistrationFileButton  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp
    end
    
    properties (Access = public)
        save_flag
        cal_file1
        im_data_cal

        layout1

        threshold_bw
        ellipse_mask
        resample_size
        stretched_size
        tform_list
        tform_init
        center_frame_idx

        img_fix
        centroids
        optimized_tform_init
        optimized_tform_list
        tform_bkwd_ref
        tform_bkwd_rel
        gridX
        gridY
        patch_size
        pinhole_dia
        pinhole_spacing
        dx
        dy
        pixel_size_x
        pixel_size_y
        
        psf_fit_all
        psf_mean_all
        PSFs_raw
        PSFs
        PSFs_warped
        PSFs_warped_all_view
        basis_psfs
        weights
        weights_fitted
        
        sigma_x_all
        sigma_y_all
        theta_all
        M2
    end
    
    methods (Access = public)
        
        function sliceViewerPlot1(app, idx)
            [~,~,w] = size(app.im_data_cal{1});
            for i=1:5
                ax = nexttile(app.layout1,i);
                imagesc(ax, app.im_data_cal{i}(:,:,idx));
                axis(ax,'off')
            end
            app.Label1.Text = sprintf('%.0f/%.0f', idx, w);
        end
        function sliceViewerPlot2(app, view_idx, depth_idx)
            [num_view,num_frame] = size(app.M2);
            imshow(app.M2{view_idx,depth_idx}.cdata, 'Parent',app.UIAxes2);
            app.Label1_5.Text = sprintf('%.0f/%.0f', view_idx, num_view);
            app.Label1_2.Text = sprintf('%.0f/%.0f', depth_idx, num_frame);
        end
        
        function save_params(app)
            % save
            patch_size = app.patch_size;
            PSFs =  app.PSFs;
            optimized_tform_list = app.optimized_tform_list;
            pixel_size_x = app.pixel_size_x;
            pixel_size_y = app.pixel_size_y;

            PSFs_warped =  app.PSFs_warped;
            PSFs_warped_all_view =  app.PSFs_warped_all_view;
            tform_bkwd_ref = app.tform_bkwd_ref;
            tform_bkwd_rel = app.tform_bkwd_rel;
            
            filename = [datestr(now, 'yymmdd'),'_psf_1.mat'];
            [pathname,~,~] = fileparts(app.cal_file1);
            [filename, pathname] = uiputfile(fullfile(pathname,filename),...
                'Save the calibration file:');
            app.save_flag = true;
            if isequal(filename,0) || isequal(pathname,0)
                return;
            end
            FileName = fullfile(pathname, filename);
            save(FileName,'patch_size','PSFs',...
                'optimized_tform_list','pixel_size_x','pixel_size_y',...
                "tform_bkwd_rel","tform_bkwd_ref",...
                "PSFs_warped","PSFs_warped_all_view");
            msgbox('PSF Cal. file saved!')
            % saveAffineList(optimized_tform_list, FileName); 
            % Call main app's public function
            try
                updateParams2(app.MainApp, pathname, FileName);
                msgbox('back to Mainapp!','modal')
            end
        end
        % 计算PSF半宽的辅助函数
        function half_width = calculateHalfWidth(app, psf_slice, direction)
            % 计算PSF沿指定方向的半宽

            if strcmp(direction, 'x')
                % 沿x方向计算(沿y求和)
                profile = sum(psf_slice, 1);
            else
                % 沿y方向计算(沿x求和)
                profile = sum(psf_slice, 2)';
            end

            % 归一化轮廓
            profile = profile / max(profile);

            % 找到半宽(半高宽)
            [~, max_idx] = max(profile);
            left_idx = find(profile(1:max_idx) <= 0.5, 1, 'last');
            right_idx = max_idx + find(profile(max_idx:end) <= 0.5, 1, 'first') - 1;

            if isempty(left_idx)
                left_idx = 1;
            end

            if isempty(right_idx)
                right_idx = length(profile);
            end

            half_width = right_idx - left_idx;
        end
    end

    methods (Access = private)

        function load_data(app)
            % load cal file
            S = load(app.cal_file1);
            cal_filename = S.cal_filename;
            bk_filename = S.bk_filename;
            app.threshold_bw = S.threshold_bw;
            app.ellipse_mask = S.ellipse_mask;
            app.resample_size = S.resample_size;
            app.stretched_size = S.stretched_size;
            app.tform_list = S.tform_list;
            app.tform_init = S.tform_init;
            app.center_frame_idx = S.center_frame_idx;
            % load cal data
            S = load(cal_filename,'imgcube');
            im_data = rescale(S.imgcube);
            % load bk data
            S = load(bk_filename,'bk');
            bk = mean(rescale(S.bk),3);

            % % Crop out sub-apertures and stretch data images
            % init container
            app.im_data_cal = cell(1,length(app.ellipse_mask));
            % loop through apertures
            im_data2 = imresize(im_data,app.resample_size);
            for i=1:length(app.ellipse_mask)
                % Find the bounding box of each ellipse region
                [rows, cols] = find(app.ellipse_mask{i});
                row_min = min(rows);
                row_max = max(rows);
                col_min = min(cols);
                col_max = max(cols);
                % Extract the subimages
                im_data_sub = im_data2(row_min:row_max, col_min:col_max, :);
                % normalize
                im_data_sub = im_data_sub - min(im_data_sub(app.ellipse_mask{i}),[],"all");
                im_data_sub(im_data_sub<0) = 0;
                % Stretch the subimage to make the ellipse a circle
                im_data_sub = imresize(im_data_sub,app.stretched_size);
                % save sub-images
                app.im_data_cal{i} = im_data_sub;
                % show data
                sliceViewerPlot1(app,1);
            end
            app.ReconSizeLabel.Text = ['Recon. Size: ' num2str(app.stretched_size(1)) 'x' num2str(app.stretched_size(2))];
            msgbox('Loaded!')
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, cal_file1)
            addpath('utils\')
            try
                app.MainApp = mainapp;
                app.cal_file1 = cal_file1;
            catch
                app.cal_file1 = 0;
            end
            app.save_flag = false;
            app.layout1 = tiledlayout(app.Panel,2,3,'TileSpacing','none');
        end

        % Button pushed function: ImportRegistrationFileButton
        function ImportRegistrationFileButtonPushed(app, event)
            
            try
                load_data(app);
            catch
                [file,location] = uigetfile('*.mat');
                app.cal_file1 = fullfile(location,file);
                load_data(app);
            end
            figure(app.UIFigure);drawnow
        end

        % Button pushed function: Button_left, Button_right
        function Button_leftPushed(app, event)
            imgViewInfo = regexp(app.Label1.Text, '(?<value>\d+)/(?<total>\d+)', 'names');
            imgViewInfo.value = str2double(imgViewInfo.value);
            imgViewInfo.total = str2double(imgViewInfo.total);
            switch event.Source
                case app.Button_left
                    if imgViewInfo.value > 1
                        imgViewInfo.value = imgViewInfo.value-1;
                    end

                case app.Button_right
                    if imgViewInfo.value < imgViewInfo.total
                        imgViewInfo.value = imgViewInfo.value+1;
                    end
            end

            sliceViewerPlot1(app, imgViewInfo.value)
        end

        % Button pushed function: TemplatematchingforcentralframeButton
        function TemplatematchingforcentralframeButtonPushed(app, event)
            % center frame
            app.img_fix = app.im_data_cal{3}(:,:,app.center_frame_idx);
            app.img_fix = imwarp(app.img_fix, app.tform_init, 'OutputView', imref2d(size(app.img_fix)));
            num_view = length(app.im_data_cal);
            num_frame = size(app.im_data_cal{3},3);
            % blend overlay images
            im_trans = cell(1,num_view);
            for i=1:num_view
                for j=1:num_frame
                    img_mov = rescale(app.im_data_cal{i}(:,:,j));
                    tform = app.tform_list(i,j);
                    im_trans{i}(:,:,j) = imwarp(img_mov, tform, 'OutputView', imref2d(size(app.img_fix)));
                end
            end
            addpath(genpath("MIMT\"))
            im_blend = blend_images(cat(3,im_trans{:}), 0.1);

            % choose cropping rect
            figure
            [~,rect] = imcrop(im_blend);
            close
            rect = round(rect);
            im_crop = imcrop(app.img_fix, rect);
            bw = imbinarize(im_crop);
            bw = restoreCroppedImage(bw,rect,size(app.img_fix));
            minArea = 10;  % 设置最小区域面积
            bw = bwareaopen(bw, minArea);
            % 计算每个连接区域的质心
            stats = regionprops(bw, 'Centroid');
            % 提取所有质心坐标
            app.centroids = cat(1, stats.Centroid);
            % 显示结果
            imagesc(app.UIAxes, app.img_fix); % 显示原始灰度图像
            colormap(app.UIAxes, 'gray')
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.centroids(:,1), app.centroids(:,2), 'rx'); % 标记质心
            hold(app.UIAxes,'off');
            axis(app.UIAxes,'tight')
        end

        % Button pushed function: StartcentroidsfittingButton
        function StartcentroidsfittingButtonPushed(app, event)
            numRows = app.RowsSpinner.Value;
            numCols = round(size(app.centroids,1) / numRows);
            num_view = length(app.im_data_cal);
            num_frame = size(app.im_data_cal{3},3);
            %%  Fit template grid based on centroids
            % Sort the points by column
            [~, colIdx] = sort(app.centroids(:,1));
            sortedCentroids = app.centroids(colIdx,:);
            % Sort each row by column in descending order
            for i = 1:numCols
                colIndices = (i-1)*numRows+1:i*numRows;
                [~, rowIdx] = sort(sortedCentroids(colIndices,2), 'ascend');
                sortedCentroids(colIndices,:) = sortedCentroids(colIndices(rowIdx),:);
            end
            Y = reshape(sortedCentroids(:,2),numRows,numCols);
            X = reshape(sortedCentroids(:,1),numRows,numCols);

            % Calculate the average spacing between circle centers in each row and column
            app.dy = mean(mean(diff(Y,1,1)));
            dy_std = std(diff(Y,1,1),0,"all");
            app.dx = mean(mean(diff(X,1,2)));
            dx_std = std(diff(X,1,2),0,"all");

            % Calculate the grid range based on the sample data and grid spacing
            minX = mean(X(:)) - (numCols-1)/2 * app.dx;
            maxX = mean(X(:)) + (numCols-1)/2 * app.dx;
            minY = mean(Y(:)) - (numRows-1)/2 * app.dy;
            maxY = mean(Y(:)) + (numRows-1)/2 * app.dy;
            % Create the grid coordinate matrix
            [app.gridX, app.gridY] = meshgrid(minX:app.dx:maxX, minY:app.dy:maxY);
            
            % calculate pixel size
            % app.pinhole_spacing = 100;
            % app.pinhole_dia = 20;
            switch app.PatternDropDown.Value
                case '1' % 20u pinhole, 100u spacing
                    app.pinhole_spacing = 100;
                    app.pinhole_dia = 20;
                case '2' % 40u pinhole, 200u spacing
                    app.pinhole_spacing = 200;
                    app.pinhole_dia = 40;
                case '3' % 80u pinhole, 400u spacing
                    app.pinhole_spacing = 400;
                    app.pinhole_dia = 80;
            end
            app.pixel_size_x = app.pinhole_spacing/app.dx;
            app.pixel_size_y = app.pinhole_spacing/app.dy;
            % get resize ratio
            sy = app.resample_size(1)/640;
            sx = app.resample_size(2)/512;
            [rows, cols] = find(app.ellipse_mask{1});
            row_min = min(rows); row_max = max(rows);
            col_min = min(cols); col_max = max(cols);
            sy = sy * app.stretched_size(1)/(row_max-row_min+1);
            sx = sx * app.stretched_size(2)/(col_max-col_min+1);
            Mx = 15/sx/app.pixel_size_x;
            My = 15/sy/app.pixel_size_y;
            app.M_xM_yLabel.Text = ['M_x = ' num2str(Mx,3) '; M_y = ' num2str(My,3)];
            app.PixelszLabel.Text = ['pix Size: ' num2str(app.pixel_size_y,2) 'x' num2str(app.pixel_size_x,2) ' um'];
            %% Solve template-to-center-frame registration
            app.patch_size = [app.PatchsizepixelEditField.Value, app.PatchsizepixelEditField.Value];
            % 网格点上求解变换
            [app.optimized_tform_init, err] = optimizeCentroidTransform(app.gridX, app.gridY, app.img_fix, app.patch_size);
            % img_trans = imwarp(app.img_fix, app.optimized_tform_init, 'OutputView', imref2d(size(app.img_fix)));
            
            %% Solve registration for all
            app.optimized_tform_list = repmat(affinetform2d(),size(app.tform_list));
            overall_progress = 0;
            total_steps = length(app.im_data_cal) * num_frame;
            h = waitbar(0, 'Processing...');
            start_time = tic;
            fig = figure;
            app.M2 = cell(num_view,num_frame);
            for i=1:num_view
                for j=1:num_frame
                    % first tform: to central view
                    img_reg = imwarp(app.im_data_cal{i}(:,:,j), app.tform_list(i,j), 'OutputView', imref2d(size(app.img_fix)));
                    % second tform: to template view
                    img_reg = imwarp(img_reg, app.optimized_tform_init, 'OutputView', imref2d(size(app.img_fix)));
                    % 网格点上求解affine变换
                    [tform,err] = optimizeCentroidTransform(app.gridX, app.gridY, img_reg, app.patch_size);
                    % 级联tforms并存储
                    % optimized_tform_list(i,j) = projtform2d(tform.A * optimized_tform_init.A * tform_list(i,j).A);
                    app.optimized_tform_list(i,j) = affinetform2d(tform.A * app.optimized_tform_init.A * app.tform_list(i,j).A);
                    % 更新图窗
                    tiledlayout(numRows,numCols,"TileSpacing","none");
                    img_trans = imwarp(app.im_data_cal{i}(:,:,j), app.optimized_tform_list(i,j),...
                        'OutputView', imref2d(size(app.img_fix)));
                    for ii=1:numRows
                        for jj=1:numCols
                            nexttile
                            x = app.gridX(ii, jj);
                            y = app.gridY(ii, jj);
                            patch = imcrop(img_trans, [x - app.patch_size(2)/2, y - app.patch_size(1)/2, app.patch_size(2)-1, app.patch_size(1)-1]);
                            imagesc(patch); hold on;
                            plot(ceil(app.patch_size(2)/2), ceil(app.patch_size(1)/2), 'rx');
                        end
                    end
                    sgtitle(sprintf('View#%d Frame#%d\nAvg.Err: %.1f Std: %.2f',i,j,mean(err), std(err)));
                    drawnow
                    app.M2{i,j} = getframe(gcf);
                    % 更新进度
                    overall_progress = overall_progress + 1;
                    elapsed_time = toc(start_time);
                    estimated_total_time = (elapsed_time / overall_progress) * total_steps;
                    remaining_time = estimated_total_time - elapsed_time;
                    waitbar_message = sprintf('Frame%d of View%d Avg.Err: %.1f Std: %.2f\nElapsed: %.2fs, Remaining: %.2fs', ...
                        j, i,mean(err), std(err), elapsed_time, remaining_time);
                    waitbar(overall_progress / num_view / num_frame, h, waitbar_message);
                end
            end
            close(h)
            close(fig)
            sliceViewerPlot2(app, 1, 1)
            % fitting and decompose transformation
            % optimized_tform on x is tform_bkwd_rel(tform_bkwd_ref(x))
            [app.tform_bkwd_ref, app.tform_bkwd_rel] = coordinate_transform(app.optimized_tform_list, app.center_frame_idx);
            for i=1:num_view
                for j=1:num_frame
                    t_ref = app.tform_bkwd_ref(i);
                    t_rel = app.tform_bkwd_rel(i,j);
                    app.optimized_tform_list(i,j) = affinetform2d(t_rel.A * t_ref.A);
                end
            end
            
        end

        % Button pushed function: ReExtractPSFsButton
        function ReExtractPSFsButtonPushed(app, event)
            num_frame = size(app.im_data_cal{1},3);
            num_view = length(app.im_data_cal);

            nIter = app.PSFEstimationnItersSpinner.Value;
            app.psf_fit_all = zeros(app.patch_size(1),app.patch_size(2),num_frame,num_view);
            app.psf_mean_all = zeros(app.patch_size(1),app.patch_size(2),num_frame,num_view);

            figure;
            % Store PSF parameters
            app.sigma_x_all = zeros(num_view, num_frame);
            app.sigma_y_all = zeros(num_view, num_frame);
            app.theta_all = zeros(num_view, num_frame);
            for i=1:num_view
                for j=1:num_frame
                    % apply transformation
                    img_trans = imwarp(app.im_data_cal{i}(:,:,j),...
                                app.optimized_tform_list(i,j),...
                                'OutputView', imref2d(size(app.img_fix)));
                    % fit PSFs using 2D Gaussian model
                    [psf_mean,psf_fit,psf_params] = fittingPSF(app.gridX, app.gridY,...
                                            img_trans, app.patch_size,...
                                            app.pinhole_dia/app.pixel_size_x,...
                                            app.pinhole_dia/app.pixel_size_y,nIter);
                    % save
                    app.psf_fit_all(:,:,j,i) = psf_fit;
                    app.psf_mean_all(:,:,j,i) = psf_mean;
                    
                    % Store parameters directly in arrays
                    app.sigma_x_all(i,j) = psf_params(3) * app.pixel_size_x;  % Convert to um
                    app.sigma_y_all(i,j) = psf_params(4) * app.pixel_size_y;  % Convert to um
                    app.theta_all(i,j) = rad2deg(mod(psf_params(5), pi));     % Convert to degrees

                    ax = subplot(321);
                    % imagesc(ax,psf_mean);
                    imshow(rescale(psf_mean),'Parent',ax)
                    title('Mean')
                    addScaleBar(ax, 15/app.pixel_size_x, '15 um', 10)
                    ax = subplot(322);
                    % imagesc(psf_fit);
                    imshow(rescale(psf_fit),'Parent',ax)
                    title('Fitted')
                    addScaleBar(ax, 15/app.pixel_size_x, '15 um', 10)
                    subplot(323);
                    y = psf_mean(round(size(psf_fit,1)/2),:);
                    x = (1:length(y))*app.pixel_size_x;
                    plot(x,y);xlabel('um'); axis tight;
                    subplot(324);
                    y = psf_fit(round(size(psf_fit,1)/2),:);
                    x = (1:length(y))*app.pixel_size_x;
                    plot(x,y);xlabel('um'); axis tight;
                    ax = subplot(325);
                    cla(ax);
                    axis off;
                    text(0.1, 0.5, {sprintf('$\\sigma_x$: %.2f um',psf_params(3)*app.pixel_size_x);...
                                sprintf('$\\sigma_y$: %.2f um',psf_params(4)*app.pixel_size_y);...
                                sprintf('$\\theta$: %.1f $^\\circ$',rad2deg(mod(psf_params(5),pi)))}, ...
                        'FontSize', 12, 'Color', 'r','Interpreter','latex');
                    sgtitle(sprintf('View#%d Frame#%d',i,j));
                    drawnow; 
                end
            end
            close;

            %% Plot the mean/Gaussian fitted PSF parameters directly in UIAxes3/4
            ViewDropDownValueChanged(app);

            %% choose which PSF to use
            if app.UseGaussianfittedPSFCheckBox.Value
                app.PSFs_raw = app.psf_fit_all;
            else
                app.PSFs_raw = app.psf_mean_all;
            end

            %% low-rank fit PSFs
            K = app.PCAorderlowrankSpinner.Value;
            [app.PSFs, app.basis_psfs, app.weights, app.weights_fitted] = psf_lowrank_fit(app.PSFs_raw, K);
            
            %% warp PSFs
            [app.PSFs_warped,app.PSFs_warped_all_view] = warpPSFs(app.PSFs, app.stretched_size, app.tform_bkwd_rel);
            
            
        end

        % Button pushed function: Button_left_2, Button_left_5, 
        % ...and 2 other components
        function Button_left_2Pushed(app, event)
            imgViewInfo = regexp(app.Label1_5.Text, '(?<value>\d+)/(?<total>\d+)', 'names');
            imgViewInfo.value = str2double(imgViewInfo.value);
            imgViewInfo.total = str2double(imgViewInfo.total);
            switch event.Source
                case app.Button_left_5
                    if imgViewInfo.value > 1
                        imgViewInfo.value = imgViewInfo.value-1;
                    end

                case app.Button_right_5
                    if imgViewInfo.value < imgViewInfo.total
                        imgViewInfo.value = imgViewInfo.value+1;
                    end
            end
            view_idx = imgViewInfo.value;

            imgViewInfo = regexp(app.Label1_2.Text, '(?<value>\d+)/(?<total>\d+)', 'names');
            imgViewInfo.value = str2double(imgViewInfo.value);
            imgViewInfo.total = str2double(imgViewInfo.total);
            switch event.Source
                case app.Button_left_2
                    if imgViewInfo.value > 1
                        imgViewInfo.value = imgViewInfo.value-1;
                    end

                case app.Button_right_2
                    if imgViewInfo.value < imgViewInfo.total
                        imgViewInfo.value = imgViewInfo.value+1;
                    end
            end
            depth_idx = imgViewInfo.value;

            sliceViewerPlot2(app, view_idx, depth_idx)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~app.save_flag
                save_params(app)
            end
            try
                app.MainApp.StartPSFestimationButton.Enable = "on";
                % switch back to main app
                figure(app.MainApp.UIFigure); drawnow
                load_cal2(app.MainApp);
            end
            delete(app)
        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            save_params(app)
            try
                app.MainApp.StartPSFestimationButton.Enable = "on";
                % switch back to main app
                figure(app.MainApp.UIFigure); drawnow
                load_cal2(app.MainApp);
            end
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            % 获取下拉菜单中选择的view
            selectedView = str2double(app.ViewDropDown.Value);
            [num_view, num_frame] = size(app.sigma_x_all);
            
            % 创建两个子图(1x2网格)
            % 第一个子图：合并sigma_x, sigma_y和theta
            cla(app.UIAxes3, 'reset');
            cla(app.UIAxes4, 'reset');
            yyaxis(app.UIAxes3,"left");
            hold(app.UIAxes3, 'on');
            plot(app.UIAxes3,1:num_frame, app.sigma_x_all(selectedView,:), 'LineWidth', 1, 'DisplayName', '\\sigma_x');
            plot(app.UIAxes3,1:num_frame, app.sigma_y_all(selectedView,:), 'LineWidth', 1, 'DisplayName', '\\sigma_y');
            hold(app.UIAxes3, 'off');
            ylabel(app.UIAxes3,'\sigma_x, \sigma_y (μm)');
            yyaxis(app.UIAxes3,"right");
            plot(app.UIAxes3,1:num_frame, app.theta_all(selectedView,:), 'LineWidth', 1.5, 'DisplayName', '\theta');
            ylabel(app.UIAxes3,'\theta (degrees)');

            title(app.UIAxes3,'PSF Parameters vs Frame');
            xlabel(app.UIAxes3,'Frame Number');
            grid(app.UIAxes3,"on");
            legend(app.UIAxes3,'Location', 'best');
            hold(app.UIAxes3,'off');

            % 第二个子图：显示选中view的3D PSF半宽
            % 获取选中view的PSF数据
            psf_fit = app.psf_fit_all(:, :, :, selectedView);
            psf_mean = app.psf_mean_all(:, :, :, selectedView);

            % 计算两种PSF类型沿X和Y的半宽
            [~, ~, num_z] = size(psf_fit);

            % 计算psf_fit和psf_mean沿X和Y的半宽
            psf_fit_half_width_x = zeros(1, num_z);
            psf_fit_half_width_y = zeros(1, num_z);
            psf_mean_half_width_x = zeros(1, num_z);
            psf_mean_half_width_y = zeros(1, num_z);

            for z = 1:num_z
                % 处理psf_fit
                psf_slice = psf_fit(:, :, z);
                psf_fit_half_width_x(z) = calculateHalfWidth(app,psf_slice, 'x');
                psf_fit_half_width_y(z) = calculateHalfWidth(app,psf_slice, 'y');

                % 处理psf_mean
                psf_slice = psf_mean(:, :, z);
                psf_mean_half_width_x(z) = calculateHalfWidth(app,psf_slice, 'x');
                psf_mean_half_width_y(z) = calculateHalfWidth(app,psf_slice, 'y');
            end

            % 绘制半宽与深度的关系图
            hold(app.UIAxes4,"on");
            % psf_fit - 实线
            plot(app.UIAxes4,1:num_z, psf_fit_half_width_x, '-b', 'LineWidth', 1.5, 'DisplayName', 'PSF Fit X');
            plot(app.UIAxes4,1:num_z, psf_fit_half_width_y, '-r', 'LineWidth', 1.5, 'DisplayName', 'PSF Fit Y');

            % psf_mean - 虚线
            plot(app.UIAxes4,1:num_z, psf_mean_half_width_x, '--b', 'LineWidth', 1.5, 'DisplayName', 'PSF Mean X');
            plot(app.UIAxes4,1:num_z, psf_mean_half_width_y, '--r', 'LineWidth', 1.5, 'DisplayName', 'PSF Mean Y');

            title(app.UIAxes4,sprintf('PSF Half-Width vs Depth (View %d)', selectedView));
            xlabel(app.UIAxes4,'Depth');
            ylabel(app.UIAxes4,'Half-Width (pixels)');
            grid(app.UIAxes4,"on");
            legend(app.UIAxes4,'Location', 'best');
            hold(app.UIAxes4,"off");
        end

        % Button pushed function: ShowWarped3DPSFButton
        function ShowWarped3DPSFButtonPushed(app, event)
            % 用imagesc画PSFs_warped的XZ和YZ切片，用hot colormap
            num_view = length(app.im_data_cal);
            XZ_slice = squeeze(mean(app.PSFs_warped,2));
            figure
            tlayout = tiledlayout(3,5,"TileSpacing","compact");
            nexttile(tlayout,[1,5])
            imagesc(XZ_slice');
            colormap('hot');
            title('XZ Proj.');
            xlabel('Z');
            ylabel('X');
            axis('tight');
            % Next tile for YZ slice
            % Display YZ slice (assuming the middle slice in X direction)
            nexttile(tlayout,[1,5])
            midX = round(size(app.PSFs_warped, 1)/2);
            YZ_slice = squeeze(mean(app.PSFs_warped,1));
            imagesc(YZ_slice');
            colormap('hot');
            title('YZ Proj.');
            xlabel('Z');
            ylabel('Y');
            axis('tight');
            for i=1:num_view
                nexttile(tlayout)
                rgb = color_coded_proj(app.PSFs_warped_all_view(:,:,:,i));
                imshow(rgb); axis on
            end
            function rgb = color_coded_proj(psf)
                [M, N, P] = size(psf);
                % 创建Z轴索引矩阵
                z_indices = reshape(1:P, [1, 1, P]);
                z_indices = repmat(z_indices, [M, N, 1]);
                % 计算总强度，用于归一化
                total_intensity = sum(psf, 3);
                % 避免除以零
                total_intensity(total_intensity == 0) = 1;
                % 计算加权平均Z位置
                weighted_z = sum(psf .* z_indices, 3) ./ total_intensity;
                % 归一化Z位置到[0,1]范围
                z_norm = (weighted_z - 1) / (P - 1);
                % 使用最大强度投影来确定亮度
                [max_proj, ~] = max(psf, [], 3);
                intensity_norm = max_proj / max(max_proj(:));
                % 创建RGB图像
                % 1. 准备颜色映射 (使用彩虹色谱)
                cmap = turbo(256);
                % 2. 将z_norm转换为颜色索引
                color_indices = min(max(round(z_norm(:) * 255) + 1, 1), 256);
                % 3. 获取对应的颜色
                colors = cmap(color_indices, :);
                % 4. 重塑为原始图像尺寸的RGB格式
                colors = reshape(colors, [M, N, 3]);
                % 5. 应用强度调制，确保低强度区域仍可见
                min_brightness = 0;
                brightness_factors = min_brightness + (1 - min_brightness) * intensity_norm;
                % 6. 应用亮度因子到每个颜色通道
                rgb = zeros(M, N, 3);
                for c = 1:3
                    rgb(:,:,c) = colors(:,:,c) .* brightness_factors;
                end
                rgb = cropPSF(rgb,[100,100,3]);
            end
        end

        % Button pushed function: ShowPSFsreconcoordButton
        function ShowPSFsreconcoordButtonPushed(app, event)
            %% show psfs
            figure;
            num_frame = size(app.im_data_cal{1},3);
            num_view = length(app.im_data_cal);
            t = tiledlayout(num_view,num_frame,"TileSpacing","none");
            for i=1:num_view
                for j=1:num_frame
                    psf = app.PSFs(:,:,j,i);
                    psf = cropPSF(psf,[20,20]);
                    ax = nexttile(t);
                    imagesc(ax,psf);
                    colormap(ax,'sky')
                    % axis(ax,'off');
                    % imshow(rescale(psf),'Parent',ax)
                    addScaleBar(ax, 5, [num2str(5*app.pixel_size_x,'%.0f') 'u'], 7)
                    % axis(ax,'image')
                end
            end
            sgtitle('Patch size: 20\times 20 px')
        end

        % Button pushed function: ShowPSFssensorcoordButton
        function ShowPSFssensorcoordButtonPushed(app, event)
            %% show raw psfs
            num_frame = size(app.im_data_cal{1},3);
            num_view = length(app.im_data_cal);
            % get center grid point
            [rows, cols] = size(app.gridX);
            centerRow = round(rows / 2);
            centerCol = round(cols / 2);
            gdX = app.gridX(centerRow, centerCol);
            gdY = app.gridY(centerRow, centerCol);
            % get resize ratio
            sy = app.resample_size(1)/640;
            sx = app.resample_size(2)/512;
            [rows, cols] = find(app.ellipse_mask{1});
            row_min = min(rows); row_max = max(rows);
            col_min = min(cols); col_max = max(cols);
            sy = sy * app.stretched_size(1)/(row_max-row_min+1);
            sx = sx * app.stretched_size(2)/(col_max-col_min+1);
            fig = figure;
            t = tiledlayout(num_view,num_frame,"TileSpacing","none");
            for i=1:num_view
                for j=1:num_frame
                    originalSize = size(app.im_data_cal{i}(:,:,j));
                    psf = app.PSFs(:,:,j,i);
                    psf_raw = getRawPSF(psf, originalSize, gdX, gdY, app.optimized_tform_list(i,j));
                    psf_raw = imresize(psf_raw,Scale=[1/sy,1/sx]);
                    psf_raw = cropPSF(psf_raw,[10,10]);
                    ax = nexttile(t);
                    % imshow(rescale(psf_raw),'Parent',ax)
                    imagesc(ax,psf_raw);colormap(ax,'sky')
                    % axis(ax,'image')
                end
            end
        end

        % Button pushed function: ShowLowrankapproxButton
        function ShowLowrankapproxButtonPushed(app, event)
            % Get data dimensions
            [nx, ny, K, nViews] = size(app.basis_psfs);
            nz = size(app.weights, 1);

            % Create a new figure window
            fig = figure('Name', 'PSF Visualization', ...
                'Position', [100, 100, 1200, 800], ... % Increased height for additional row
                'NumberTitle', 'off', ...
                'MenuBar', 'none', ...
                'ToolBar', 'figure');

            % UI Control panel
            controlPanel = uipanel('Parent', fig, ...
                'Position', [0.01, 0.01, 0.98, 0.09]);

            % Create dropdown menu for view selection
            uicontrol('Parent', controlPanel, ...
                'Style', 'text', ...
                'String', 'Select View:', ...
                'Position', [20, 25, 80, 20]);

            viewDropdown = uicontrol('Parent', controlPanel, ...
                'Style', 'popupmenu', ...
                'String', arrayfun(@(x) ['View ' num2str(x)], 1:nViews, 'UniformOutput', false), ...
                'Position', [110, 25, 100, 20], ...
                'Value', 1);

            % Create z-slice slider control
            uicontrol('Parent', controlPanel, ...
                'Style', 'text', ...
                'String', 'Z-Slice:', ...
                'Position', [250, 25, 60, 20]);

            zSlider = uicontrol('Parent', controlPanel, ...
                'Style', 'slider', ...
                'Min', 1, ...
                'Max', nz, ...
                'Value', ceil(nz/2), ... % Start in the middle
                'SliderStep', [1/(nz-1), 10/(nz-1)], ... % Step by 1 or 10
                'Position', [320, 25, 200, 20]);

            % Z-slice text indicator
            zText = uicontrol('Parent', controlPanel, ...
                'Style', 'text', ...
                'String', ['Z: ' num2str(ceil(nz/2))], ...
                'Position', [530, 25, 60, 20]);

            % Create visualization panel
            visualPanel = uipanel('Parent', fig, ...
                'Position', [0.01, 0.1, 0.98, 0.89]);

            % Store necessary data in the figure's UserData
            figData.basis_psfs = app.basis_psfs;
            figData.weights = app.weights;
            figData.weights_fitted = app.weights_fitted;
            figData.PSFs = app.PSFs;          % Add approximated 3D PSFs
            figData.PSFs_raw = app.PSFs_raw;  % Add original 3D PSFs
            figData.panel = visualPanel;
            figData.zText = zText;            % Store reference to z text label
            set(fig, 'UserData', figData);

            % Set callbacks for controls
            set(viewDropdown, 'Callback', @updateFigureVisualization);
            set(zSlider, 'Callback', @updateZSlice);

            % Initialize visualization
            updateFigureVisualization(viewDropdown, []);

            function updateZSlice(src, ~)
                % Get current figure and its stored data
                fig = ancestor(src, 'figure');
                figData = get(fig, 'UserData');
                visualPanel = figData.panel;
                PSFs = figData.PSFs;
                PSFs_raw = figData.PSFs_raw;

                % Update z-slice text
                zValue = round(get(src, 'Value'));
                % Update z text
                set(figData.zText, 'String', ['Z: ' num2str(zValue)]);
                % Get current view from dropdown
                viewControl = findobj(fig, 'Style', 'popupmenu');
                currentView = get(viewControl, 'Value');

                % Get the axes for PSF slices
                rawPSFAxes = findobj(fig, 'Tag', 'RawPSFAxes');
                approxPSFAxes = findobj(fig, 'Tag', 'ApproxPSFAxes');
                CompPSFAxes = findobj(fig, 'Tag', 'CompPSFAxes');

                if ~isempty(rawPSFAxes) && ~isempty(approxPSFAxes) && ~isempty(CompPSFAxes)
                    % Update raw PSF slice
                    imagesc(rawPSFAxes, PSFs_raw(:,:,zValue,currentView));
                    colormap(rawPSFAxes, hot);
                    title(rawPSFAxes, ['Original PSF (Z=' num2str(zValue) ')']);
                    % 重新设置标签
                    set(rawPSFAxes, 'Tag', 'RawPSFAxes');

                    % Update approximated PSF slice
                    imagesc(approxPSFAxes, PSFs(:,:,zValue,currentView));
                    colormap(approxPSFAxes, hot);
                    title(approxPSFAxes, ['Approx. PSF (Z=' num2str(zValue) ')']);
                    % 重新设置标签
                    set(approxPSFAxes, 'Tag', 'ApproxPSFAxes');

                    % Update Compare PSF z-slice
                    imshowpair(PSFs(:,:,zValue,currentView),PSFs_raw(:,:,zValue,currentView),'Parent',CompPSFAxes);
                    axis(CompPSFAxes,'auto');
                    title(CompPSFAxes, ['Compare PSF (Z=' num2str(zValue) ')']);
                    set(CompPSFAxes, 'Tag', 'CompPSFAxes'); % Tag for later reference
                end
            end

            function updateFigureVisualization(src, ~)
                % Get current figure and its stored data
                fig = ancestor(src, 'figure');
                figData = get(fig, 'UserData');
                visualPanel = figData.panel;
                basis_psfs = figData.basis_psfs;
                weights = figData.weights;
                weights_fitted = figData.weights_fitted;
                PSFs = figData.PSFs;
                PSFs_raw = figData.PSFs_raw;

                % Get current selected view
                currentView = get(src, 'Value');

                % Get current z value from slider
                zSlider = findobj(fig, 'Style', 'slider');
                zValue = round(get(zSlider, 'Value'));

                % Get data dimensions
                [~, ~, K, ~] = size(basis_psfs);

                % Clear visualization panel
                delete(allchild(visualPanel));

                % Create tiled layout for organized subplots (3 rows)
                t = tiledlayout(visualPanel, 3, max(K, 3), 'TileSpacing', 'compact', 'Padding', 'compact');
                title(t, ['PSF Visualization for View ' num2str(currentView)]);

                % First row: Visualize basis_psfs (PSF basis of different orders)
                for k = 1:K
                    ax1 = nexttile(t, k);
                    imagesc(ax1, basis_psfs(:,:,k,currentView));
                    colormap(ax1, hot); % Use hot colormap
                    colorbar(ax1);
                    title(ax1, ['PSF Basis Order ' num2str(k)]);
                    axis(ax1, 'square');
                end

                % Second row: Visualize original weights and fitted weights
                for k = 1:K
                    ax2 = nexttile(t, K + k);
                    hold(ax2, 'on');
                    % Plot original weights (scatter plot)
                    scatter(ax2, 1:size(weights,1), weights(:,k,currentView), 'o', 'filled', 'DisplayName', 'Original Weights');
                    % Plot fitted weights (line plot)
                    plot(ax2, 1:size(weights_fitted,1), weights_fitted(:,k,currentView), '-', 'LineWidth', 2, 'DisplayName', 'Fitted Weights');
                    hold(ax2, 'off');
                    title(ax2, ['Weights for Order ' num2str(k)]);
                    xlabel(ax2, 'z');
                    ylabel(ax2, 'Weight Value');
                    legend(ax2, 'Location', 'best');
                    grid(ax2, 'on');
                end

                % Third row: Visualize 3D PSF slices (original and approximated)
                % Tile for original PSF z-slice
                if K == 1
                    % Special case: only one column
                    ax3 = nexttile(t);
                    ax4 = nexttile(t);
                    ax5 = nexttile(t);
                else
                    % For K>=2, use half the width for each
                    ax3 = nexttile(t, [1 floor(K/3)]);
                    ax4 = nexttile(t, [1 floor(K/3)]);
                    ax5 = nexttile(t, [1 floor(K/3)]);
                end

                % Original PSF z-slice
                imagesc(ax3, PSFs_raw(:,:,zValue,currentView));
                colormap(ax3, hot);
                colorbar(ax3);
                title(ax3, ['Original PSF (Z=' num2str(zValue) ')']);
                set(ax3, 'Tag', 'RawPSFAxes'); % Tag for later reference

                % Approximated PSF z-slice
                imagesc(ax4, PSFs(:,:,zValue,currentView));
                colormap(ax4, hot);
                colorbar(ax4);
                title(ax4, ['Approximated PSF (Z=' num2str(zValue) ')']);
                set(ax4, 'Tag', 'ApproxPSFAxes'); % Tag for later reference

                % Compare PSF z-slice
                imshowpair(PSFs(:,:,zValue,currentView),PSFs_raw(:,:,zValue,currentView),'Parent',ax5);
                axis(ax5,'auto');
                title(ax5, ['Compare PSF (Z=' num2str(zValue) ')']);
                set(ax5, 'Tag', 'CompPSFAxes'); % Tag for later reference
            end
        end

        % Value changed function: PCAorderlowrankSpinner
        function PCAorderlowrankSpinnerValueChanged(app, event)
            UseGaussianfittedPSFCheckBoxValueChanged(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ImportRegistrationFileButton = uibutton(app.UIFigure, 'push');
            app.ImportRegistrationFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportRegistrationFileButtonPushed, true);
            app.ImportRegistrationFileButton.Text = '1. Import Registration File';
            app.TemplatematchingforcentralframeButton = uibutton(app.UIFigure, 'push');
            app.TemplatematchingforcentralframeButton.ButtonPushedFcn = createCallbackFcn(app, @TemplatematchingforcentralframeButtonPushed, true);
            app.TemplatematchingforcentralframeButton.Text = '2. Template matching for central frame';
            app.StartcentroidsfittingButton = uibutton(app.UIFigure, 'push');
            app.StartcentroidsfittingButton.ButtonPushedFcn = createCallbackFcn(app, @StartcentroidsfittingButtonPushed, true);
            app.StartcentroidsfittingButton.Text = '3. Start centroids fitting';
            app.ReExtractPSFsButton = uibutton(app.UIFigure, 'push');
            app.ReExtractPSFsButton.ButtonPushedFcn = createCallbackFcn(app, @ReExtractPSFsButtonPushed, true);
            app.ReExtractPSFsButton.Text = '4. (Re-) Extract PSFs';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParamTab = uitab(app.TabGroup);
            app.ParamTab.Title = 'Param.';
            app.UIAxes3 = uiaxes(app.ParamTab);
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.ParamTab);
            zlabel(app.UIAxes4, 'Z')
            app.ViewDropDownLabel = uilabel(app.ParamTab);
            app.ViewDropDownLabel.Text = 'View#';
            app.ViewDropDown = uidropdown(app.ParamTab);
            app.ViewDropDown.Items = {'1', '2', '3', '4', '5'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = '1';
            app.ShowWarped3DPSFButton = uibutton(app.ParamTab, 'push');
            app.ShowWarped3DPSFButton.ButtonPushedFcn = createCallbackFcn(app, @ShowWarped3DPSFButtonPushed, true);
            app.ShowWarped3DPSFButton.Text = 'Show Warped 3D PSF';
            app.ShowPSFsreconcoordButton = uibutton(app.ParamTab, 'push');
            app.ShowPSFsreconcoordButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPSFsreconcoordButtonPushed, true);
            app.ShowPSFsreconcoordButton.Text = 'Show PSFs (recon coord.)';
            app.ShowPSFssensorcoordButton = uibutton(app.ParamTab, 'push');
            app.ShowPSFssensorcoordButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPSFssensorcoordButtonPushed, true);
            app.ShowPSFssensorcoordButton.Text = 'Show PSFs (sensor coord.)';
            app.ShowLowrankapproxButton = uibutton(app.ParamTab, 'push');
            app.ShowLowrankapproxButton.ButtonPushedFcn = createCallbackFcn(app, @ShowLowrankapproxButtonPushed, true);
            app.ShowLowrankapproxButton.Text = 'Show Low-rank approx.';
            app.SaveExitButton = uibutton(app.UIFigure, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save & Exit';
            app.Panel = uipanel(app.UIFigure);
            app.Button_left = uibutton(app.Panel, 'push');
            app.Button_left.ButtonPushedFcn = createCallbackFcn(app, @Button_leftPushed, true);
            app.Button_left.Text = '◀';
            app.Button_right = uibutton(app.Panel, 'push');
            app.Button_right.ButtonPushedFcn = createCallbackFcn(app, @Button_leftPushed, true);
            app.Button_right.Text = '▶';
            app.Label1 = uilabel(app.Panel);
            app.Label1.Text = '1/1';
            app.RowsSpinnerLabel = uilabel(app.UIFigure);
            app.RowsSpinnerLabel.Text = 'Rows';
            app.Panel_2 = uipanel(app.UIFigure);
            app.UIAxes2 = uiaxes(app.Panel_2);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Button_left_2 = uibutton(app.Panel_2, 'push');
            app.Button_left_2.ButtonPushedFcn = createCallbackFcn(app, @Button_left_2Pushed, true);
            app.Button_left_2.Text = '◀';
            app.Button_right_2 = uibutton(app.Panel_2, 'push');
            app.Button_right_2.ButtonPushedFcn = createCallbackFcn(app, @Button_left_2Pushed, true);
            app.Button_right_2.Text = '▶';
            app.Label1_2 = uilabel(app.Panel_2);
            app.Label1_2.Text = '1/1';
            app.Button_left_5 = uibutton(app.Panel_2, 'push');
            app.Button_left_5.ButtonPushedFcn = createCallbackFcn(app, @Button_left_2Pushed, true);
            app.Button_left_5.Text = '◀';
            app.Button_right_5 = uibutton(app.Panel_2, 'push');
            app.Button_right_5.ButtonPushedFcn = createCallbackFcn(app, @Button_left_2Pushed, true);
            app.Button_right_5.Text = '▶';
            app.Label1_5 = uilabel(app.Panel_2);
            app.Label1_5.Text = '1/1';
            app.ViewLabel_2 = uilabel(app.Panel_2);
            app.ViewLabel_2.Text = 'View';
            app.DepthLabel_2 = uilabel(app.Panel_2);
            app.DepthLabel_2.Text = 'Depth';
            app.RowsSpinner = uispinner(app.UIFigure);
            app.RowsSpinner.Limits = [1 50];
            app.RowsSpinner.RoundFractionalValues = 'on';
            app.RowsSpinner.ValueDisplayFormat = '%d';
            app.RowsSpinner.Value = 6;
            app.PatternDropDownLabel = uilabel(app.UIFigure);
            app.PatternDropDownLabel.Text = 'Pattern';
            app.PatternDropDown = uidropdown(app.UIFigure);
            app.PatternDropDown.Items = {'20u pinhole, 100u spacing', '40u pinhole, 200u spacing', '80u pinhole, 400u spacing'};
            app.PatternDropDown.ItemsData = {'1', '2', '3', '4'};
            app.PatternDropDown.Value = '1';
            app.PSFEstimationnItersSpinnerLabel = uilabel(app.UIFigure);
            app.PSFEstimationnItersSpinnerLabel.Text = 'PSF Estimation nIters';
            app.PSFEstimationnItersSpinner = uispinner(app.UIFigure);
            app.PSFEstimationnItersSpinner.RoundFractionalValues = 'on';
            app.PSFEstimationnItersSpinner.Value = 30;
            app.PatchsizepixelEditFieldLabel = uilabel(app.UIFigure);
            app.PatchsizepixelEditFieldLabel.Text = 'Patch size (pixel)';
            app.PatchsizepixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.PatchsizepixelEditField.RoundFractionalValues = 'on';
            app.PatchsizepixelEditField.Value = 17;
            app.UseGaussianfittedPSFCheckBox = uicheckbox(app.UIFigure);
            app.UseGaussianfittedPSFCheckBox.Text = 'Use Gaussian fitted PSF ';
            app.PixelszLabel = uilabel(app.UIFigure);
            app.PixelszLabel.Text = 'Pixel sz:';
            app.ReconSizeLabel = uilabel(app.UIFigure);
            app.ReconSizeLabel.Text = 'Recon. Size:';
            app.PCAorderlowrankSpinnerLabel = uilabel(app.UIFigure);
            app.PCAorderlowrankSpinnerLabel.Text = 'PCA order (low-rank)';
            app.PCAorderlowrankSpinner = uispinner(app.UIFigure);
            app.PCAorderlowrankSpinner.Limits = [1 10];
            app.PCAorderlowrankSpinner.RoundFractionalValues = 'on';
            app.PCAorderlowrankSpinner.ValueChangedFcn = createCallbackFcn(app, @PCAorderlowrankSpinnerValueChanged, true);
            app.PCAorderlowrankSpinner.Value = 3;
            app.M_xM_yLabel = uilabel(app.UIFigure);
            app.M_xM_yLabel.Text = 'M_x = ?; M_y = ?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cal_psf_estimation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end