classdef cal_registration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ReconSizeDropDown            matlab.ui.control.DropDown
        ReconSizeDropDownLabel       matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        Label                        matlab.ui.control.Label
        Button_right                 matlab.ui.control.Button
        Button_left                  matlab.ui.control.Button
        AutoButton                   matlab.ui.control.Button
        AdjustSlider                 matlab.ui.control.RangeSlider
        AdjustSliderLabel            matlab.ui.control.Label
        SaveExitButton               matlab.ui.control.Button
        DepthregistrationButton      matlab.ui.control.Button
        CenterregistrationButton     matlab.ui.control.Button
        BWthresholdSlider            matlab.ui.control.Slider
        BWthresholdSliderLabel       matlab.ui.control.Label
        ImportBackgroundImageButton  matlab.ui.control.Button
        ImportCalibrationImageStackButton  matlab.ui.control.Button
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        layout
        data_path
        im_data
        im_adj
        bk
        
        cal_filename
        bk_filename
        threshold_bw
        stretched_size
        ellipse_mask
        resample_size

        im_data_cal
        pts
        center_frame_idx
        center_tform_list
        tform_init
        tform_list

        save_flag
    end
    
    properties (Access = private)
        MainApp 
    end
    
    methods (Access = public)
        
        function sliceViewerPlot1(app, idx)
            [~,~,w] = size(app.im_adj);
            imshow(app.im_adj(:,:,idx), 'Parent', app.UIAxes3);  
            app.Label.Text = sprintf('%.0f/%.0f', idx, w);
        end
        
        function save_params(app)
            % save
            cal_filename = app.cal_filename;
            bk_filename = app.bk_filename;
            threshold_bw = app.threshold_bw;
            ellipse_mask = app.ellipse_mask;
            resample_size = app.resample_size;
            stretched_size = app.stretched_size;
            tform_list = app.tform_list;
            tform_init = app.tform_init;
            center_frame_idx = app.center_frame_idx;
            
            filename = [datestr(now, 'yymmdd'),'_geo_1.mat'];
            [filename, pathname] = uiputfile(fullfile(app.data_path,filename),...
                'Save the calibration file:');
            app.save_flag = true;
            if isequal(filename,0) || isequal(pathname,0)
                return;
            end
            FileName = fullfile(pathname, filename);
            save(FileName,'cal_filename','bk_filename','threshold_bw',...
                'ellipse_mask','resample_size','stretched_size',...
                'tform_list','tform_init','center_frame_idx');
            % Call main app's public function
            try
                updateParams1(app.MainApp, pathname, FileName);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, cal_path)
            addpath('utils\')
            app.layout = tiledlayout(app.Panel,3,2);
            try
                app.MainApp = mainapp;
                app.data_path = cal_path;
            catch
                app.data_path = 0;
            end
            app.save_flag = false;
            
        end

        % Button pushed function: ImportCalibrationImageStackButton
        function ImportCalibrationImageStackButtonPushed(app, event)
            if ~isequal(app.data_path,0)
                [file,location] = uigetfile(fullfile(app.data_path,'*.mat'));
            else
                [file,location] = uigetfile('D:\zzh_data\*.mat');
            end
            figure(app.UIFigure);drawnow;
            app.data_path = location;
            if ~isequal(file,0)
                app.cal_filename = fullfile(location,file);
                S = load(app.cal_filename,'imgcube');
                app.im_data = rescale(S.imgcube);
                app.im_adj = app.im_data;
                sliceViewerPlot1(app,1);
            end
        end

        % Value changed function: AdjustSlider
        function AdjustSliderValueChanged(app, event)
            value = app.AdjustSlider.Value;
            app.im_adj = imadjustn(app.im_data, value, [0,1]);
            sliceViewerPlot1(app,1);
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            min_val_auto = double(prctile(app.im_data(:), 2)); % 2nd percentile
            max_val_auto = double(prctile(app.im_data(:), 98)); % 98th percentile
            app.AdjustSlider.Value = [min_val_auto,max_val_auto];
            AdjustSliderValueChanged(app, event);
        end

        % Button pushed function: ImportBackgroundImageButton
        function ImportBackgroundImageButtonPushed(app, event)
            [file,location] = uigetfile(fullfile(app.data_path,'*.mat'));
            figure(app.UIFigure);drawnow
            if ~isequal(file,0)
                app.bk_filename = fullfile(location,file);
                S = load(app.bk_filename,'bk');
                app.bk = mean(rescale(S.bk), 3);
                BWthresholdSliderValueChanged(app, event);
            end       
        end

        % Value changed function: BWthresholdSlider
        function BWthresholdSliderValueChanged(app, event)
            app.threshold_bw = app.BWthresholdSlider.Value;
            
            %% find regions (1-st time)
            I_bw = rescale(app.bk) > app.threshold_bw;
            se = strel('disk', 5);
            I_bw = imclose(I_bw, se);
            % Extract connected component properties
            stats = regionprops(I_bw, 'Centroid', 'Orientation', 'MajorAxisLength', 'MinorAxisLength', 'Area');
            % Sort the components by area in descending order
            [~, sorted_indices] = sort([stats.Area],'descend');
            % Select the top five components by area
            stats = stats(sorted_indices(1:min(5, length(sorted_indices))));
            centroid = cell2mat({stats.Centroid}');
            center_x  = [mean(centroid(:,1))];
            center_y = sort(centroid(:,2),'ascend');
            center_y = mean(center_y) + [-2,-1,0,1,2]*mean(diff(center_y));
            major_axis =  mean([stats.MajorAxisLength]);
            minor_axis = mean([stats.MinorAxisLength]);
            angle_axis = -mean([stats.Orientation]);

            %% resample data according to region
            % 定义图像首次重采样
            app.resample_size = [round(size(app.im_data,1)*major_axis/minor_axis), size(app.im_data,2)];
            im_bk2 = imresize(app.bk,app.resample_size);
            im_data2 = imresize(app.im_data,app.resample_size);

            %% (2-nd time ) find eclipse region for apertures
            I_bw = im2bw(im_bk2, app.threshold_bw);
            se = strel('disk', 5); 
            I_bw = imclose(I_bw, se);
            % Extract connected component properties
            stats = regionprops(I_bw, 'Centroid','Orientation', 'MajorAxisLength', 'MinorAxisLength', 'Area');
            % Sort the components by area in descending order
            [~, sorted_indices] = sort([stats.Area],'descend');
            % Select the top five components by area
            stats = stats(sorted_indices(1:min(5, length(sorted_indices))));
            centroid = cell2mat({stats.Centroid}');
            center_x  = [mean(centroid(:,1))];
            center_y = sort(centroid(:,2),'ascend');
            center_y = mean(center_y) + [-2,-1,0,1,2]*mean(diff(center_y));
            major_axis =  mean([stats.MajorAxisLength]);
            minor_axis = mean([stats.MinorAxisLength]);
            angle_axis = -mean([stats.Orientation]);

            %% show area
            imagesc(app.UIAxes, im_bk2); hold(app.UIAxes,"on");
            for i=1:length(stats)
                t = linspace(0, 2*pi, 100);
                x = major_axis / 2 * cos(t);
                y = minor_axis / 2 * sin(t);
                % Rotate the ellipse based on the orientation
                R = [cosd(angle_axis) -sind(angle_axis); ...
                    sind(angle_axis) cosd(angle_axis)];
                ellipse_coords = R * [x; y];
                % Translate the ellipse to the correct position
                x_rot = center_x + ellipse_coords(1, :);
                y_rot = center_y(i) + ellipse_coords(2, :);
                plot(app.UIAxes, x_rot, y_rot, '--r', 'LineWidth', 0.5);
            end
            axis(app.UIAxes,"tight");
            hold(app.UIAxes,"off");
            %% Calculate the ellipse mask
            [X, Y] = meshgrid(1:size(im_data2,2), 1:size(im_data2,1));
            a = major_axis / 2;
            b = minor_axis / 2;
            app.ellipse_mask = cell(1,length(center_y));
            for i=1:length(center_y)
                % Translate the coordinates to the ellipse center
                X_centered = X - center_x;
                Y_centered = Y - center_y(i);
                % Rotate the coordinates based on the orientation angle
                theta = deg2rad(angle_axis); % Convert angle to radians
                X_rot = X_centered * cos(theta) + Y_centered * sin(theta);
                Y_rot = -X_centered * sin(theta) + Y_centered * cos(theta);

                % Apply the ellipse equation on the rotated coordinates
                app.ellipse_mask{i} = (X_rot.^2 / a^2 + Y_rot.^2 / b^2) <= 1;
            end

            %% Crop out sub-apertures, stretch 
            % 第二次重采样，设置最终分辨率默认为256*256
            % define the stretching size
            recon_px_count = str2double(app.ReconSizeDropDown.Value);
            app.stretched_size = [recon_px_count, recon_px_count];
            % init container
            app.im_data_cal = cell(1,length(app.ellipse_mask));
            % loop through apertures
            for i=1:length(app.ellipse_mask)
                % Find the bounding box of each ellipse region
                [rows, cols] = find(app.ellipse_mask{i});
                row_min = min(rows);
                row_max = max(rows);
                col_min = min(cols);
                col_max = max(cols);
                % Extract the subimages
                im_data_sub = im_data2(row_min:row_max, col_min:col_max, :);
                % Stretch the subimage to make the ellipse a circle
                im_data_sub = imresize(im_data_sub,app.stretched_size);
                % save sub-images
                app.im_data_cal{i} = im_data_sub;
            end

        end

        % Button pushed function: CenterregistrationButton
        function CenterregistrationButtonPushed(app, event)
            % index of central frame (depth)
            app.center_frame_idx = floor(size(app.im_data,3)/2 +1);

            % 校准中心数据
            fig = figure;
            set(fig, 'WindowState', 'fullscreen');
            im_center = app.im_data_cal{3}(:,:,app.center_frame_idx);
            imagesc(im_center); hold on
            title('从左上角开始选四个角 顺时针')
            rect = getrect; [x1, y1] = rectCentroid(im_center,rect); plot(x1,y1,'or')
            rect = getrect; [x2, y2] = rectCentroid(im_center,rect); plot(x2,y2,'or')
            rect = getrect; [x3, y3] = rectCentroid(im_center,rect); plot(x3,y3,'or')
            rect = getrect; [x4, y4] = rectCentroid(im_center,rect); plot(x4,y4,'or')
            src_points = [x1, y1; x2, y2; x3, y3; x4, y4];
            % 计算源图像中心
            center_x = mean(src_points(:,1));
            center_y = mean(src_points(:,2));
            x = src_points(:,1) - center_x;
            y = src_points(:,2) - center_y;
            half_L = mean([mean(x(x>0))-mean(x(x<0)), mean(y(y>0))-mean(y(y<0))])/2;
            dest_points = [center_x - half_L, center_y - half_L;
                center_x + half_L, center_y - half_L;
                center_x + half_L, center_y + half_L;
                center_x - half_L, center_y + half_L];
            % 计算仿射变换矩阵
            app.tform_init = fitgeotform2d(src_points, dest_points, "affine");
            im_corrected = imwarp(im_center, app.tform_init,'OutputView',imref2d(size(im_center)));
            imagesc(im_corrected)
            ginput(1);close

            % 遍历每组数据，选择对应点
            fig = figure;
            set(fig, 'WindowState', 'fullscreen');
            for i = 1:5
                subplot(2,3,i)
                imagesc(app.im_data_cal{i}(:,:,app.center_frame_idx))
            end

            % 初始化cell数组
            app.pts = cell(1, 5);
            for i = 1:5
                im_center = rescale(app.im_data_cal{i}(:,:,app.center_frame_idx));
                subplot(2,3,i)
                hold on;
                title('Input pt #1 (rect)')
                rect = getrect;
                [x1, y1] = rectCentroid(im_center,rect);
                plot(x1, y1, 'wo');
                pt1 = [x1,y1];

                title('Input pt #2 (rect)')
                rect = getrect;
                [x2, y2] = rectCentroid(im_center,rect);
                plot([x1,x2], [y1,y2], 'w-');
                plot(x2, y2, 'wo');
                pt2 = [x2,y2];

                title('Input pt #3 (rect)')
                rect = getrect;
                [x3, y3] = rectCentroid(im_center,rect);
                plot([x2,x3], [y2,y3], 'w-');
                plot(x3, y3, 'wo');
                pt3 = [x3,y3];

                % 将端点存入
                app.pts{i} = [pt1; pt2; pt3];
            end
            close

            % 使用第三组作为模板 计算仿射变换
            fixed_pts = app.pts{3};
            app.center_tform_list = repmat(affinetform2d(),5,1);
            im_reg = zeros([size(app.im_data_cal{3},1),size(app.im_data_cal{3},2),5]);
            for i = 1:5
                % 获取当前组的点
                moving_pts = app.pts{i};
                if i == 3
                    % 单位变换
                    app.center_tform_list(i) = app.tform_init;
                else
                    % 估计仿射变换
                    tform = fitgeotform2d(moving_pts, fixed_pts, 'affine');
                    app.center_tform_list(i) = affinetform2d(app.tform_init.A*tform.A);
                end
                im_reg(:,:,i) = imwarp(rescale(app.im_data_cal{i}(:,:,app.center_frame_idx)),...
                    app.center_tform_list(i),'OutputView',imref2d(size(app.im_data_cal{3}(:,:,app.center_frame_idx))));
            end
            
            % show
            ax = nexttile(app.layout,1);
            imagesc(ax,mean(im_reg,3))
            title(ax,'Coarsely registered')
        end

        % Button pushed function: Button_left, Button_right
        function Button_leftPushed(app, event)
            imgViewInfo = regexp(app.Label.Text, '(?<value>\d+)/(?<total>\d+)', 'names');
            imgViewInfo.value = str2double(imgViewInfo.value);
            imgViewInfo.total = str2double(imgViewInfo.total);
            switch event.Source
                case app.Button_left
                    if imgViewInfo.value > 1
                        imgViewInfo.value = imgViewInfo.value-1;
                    end

                case app.Button_right
                    if imgViewInfo.value < imgViewInfo.total
                        imgViewInfo.value = imgViewInfo.value+1;
                    end
            end

            sliceViewerPlot1(app, imgViewInfo.value)
        end

        % Button pushed function: DepthregistrationButton
        function DepthregistrationButtonPushed(app, event)
            app.tform_list = repmat(app.center_tform_list,1,2);
            num_frame = size(app.im_data_cal{1},3);
            figure;
            for i=1:5
                im_reg = zeros(size(app.im_data_cal{1}));
                for j=1:num_frame
                    im_reg(:,:,j) = imwarp(rescale(app.im_data_cal{i}(:,:,j)),app.center_tform_list(i),'OutputView',imref2d(size(app.im_data_cal{i}(:,:,j))));
                end
                im_blend = blend_images(cat(3,im_reg(:,:,1),...
                    im_reg(:,:,app.center_frame_idx),...
                    im_reg(:,:,end)), 0.8);
                imshow(im_blend)
                hold on
                pt_reg = transformPointsForward(app.tform_list(i,1),app.pts{i});
                axis ij
                title(sprintf('View#%d 1-st depth marked as red',i))

                pt1 = zeros(size(pt_reg,1),2);
                pt2 = zeros(size(pt_reg,1),2);
                for j = 1:size(pt_reg,1)
                    plot(pt_reg(j,1),pt_reg(j,2), 'go');
                    % 第一帧: 框选范围
                    rect = getrect;
                    [x1, y1] = rectCentroid(im_reg(:,:,1),rect);
                    pt1(j,1) = x1; pt1(j,2) = y1;
                    % 可视化
                    plot(x1,y1,'or');
                    % 最后帧: 框选范围
                    rect = getrect;
                    [x2, y2] = rectCentroid(im_reg(:,:,end),rect);
                    pt2(j,1) = x2; pt2(j,2) = y2;
                    % 可视化
                    plot([x1,x2],[y1,y2],'r-');
                    plot(x2,y2,'or');
                end
                % 计算变换（从第一帧到中间帧）
                tform = affinetform2d(fitgeotform2d(pt1, pt_reg, 'affine'));
                app.tform_list(i,1) = affinetform2d(tform.A * app.center_tform_list(i).A);
                % 计算变换（从最后一帧到中间帧）
                tform = affinetform2d(fitgeotform2d(pt2, pt_reg, 'affine'));
                app.tform_list(i,2) = affinetform2d(tform.A * app.center_tform_list(i).A);
                % 可视化
                im_reg1 = imwarp(rescale(app.im_data_cal{i}(:,:,1)),app.tform_list(i,1),'OutputView',imref2d(size(app.im_data_cal{i}(:,:,1))));
                im_reg2 = imwarp(rescale(app.im_data_cal{i}(:,:,end)),app.tform_list(i,2),'OutputView',imref2d(size(app.im_data_cal{i}(:,:,1))));
                imshowpair(imadjust(im_reg1),...
                    imadjust(im_reg2))
                title('Click figure to continue...')
                hold off
                ginput(1);
            end
            close

            % 可视化
            for i=1:5
                ax = nexttile(app.layout,i+1);
                im_reg1 = imwarp(rescale(app.im_data_cal{i}(:,:,1)),app.tform_list(i,1),'OutputView',imref2d(size(app.im_data_cal{i}(:,:,1))));
                im_reg2 = imwarp(rescale(app.im_data_cal{i}(:,:,end)),app.tform_list(i,2),'OutputView',imref2d(size(app.im_data_cal{i}(:,:,1))));
                imshowpair(im_reg1,im_reg2,'Parent',ax)
                title(ax,sprintf('View#%d, first&last frames',i))
            end
            % interp and save
            lambda = linspace(0,1,num_frame);
            app.tform_list = interp_transformations(app.tform_list,lambda,'linear');
        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            save_params(app)
            try
                app.MainApp.StartgeometricregistrationButton.Enable = "on";
                % switch back to main app
                figure(app.MainApp.UIFigure); drawnow
                load_cal1(app.MainApp);
            end
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~app.save_flag
                save_params(app)
            end
            if isvalid(app.MainApp)
                app.MainApp.StartgeometricregistrationButton.Enable = "on";
                % switch back to main app
                figure(app.MainApp.UIFigure); drawnow
                load_cal1(app.MainApp);
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.ImportCalibrationImageStackButton = uibutton(app.UIFigure, 'push');
            app.ImportCalibrationImageStackButton.ButtonPushedFcn = createCallbackFcn(app, @ImportCalibrationImageStackButtonPushed, true);
            app.ImportCalibrationImageStackButton.Text = '1.Import Calibration Image Stack';
            app.ImportBackgroundImageButton = uibutton(app.UIFigure, 'push');
            app.ImportBackgroundImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportBackgroundImageButtonPushed, true);
            app.ImportBackgroundImageButton.Text = '2.Import Background Image';
            app.BWthresholdSliderLabel = uilabel(app.UIFigure);
            app.BWthresholdSliderLabel.Text = 'BW threshold';
            app.BWthresholdSlider = uislider(app.UIFigure);
            app.BWthresholdSlider.Limits = [0 1];
            app.BWthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @BWthresholdSliderValueChanged, true);
            app.BWthresholdSlider.Value = 0.2;
            app.CenterregistrationButton = uibutton(app.UIFigure, 'push');
            app.CenterregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @CenterregistrationButtonPushed, true);
            app.CenterregistrationButton.Text = '3.Center registration';
            app.DepthregistrationButton = uibutton(app.UIFigure, 'push');
            app.DepthregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @DepthregistrationButtonPushed, true);
            app.DepthregistrationButton.Text = '4.Depth registration';
            app.SaveExitButton = uibutton(app.UIFigure, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save & Exit';
            app.AdjustSliderLabel = uilabel(app.UIFigure);
            app.AdjustSliderLabel.Text = 'Adjust';
            app.AdjustSlider = uislider(app.UIFigure, 'range');
            app.AdjustSlider.Limits = [0 1];
            app.AdjustSlider.ValueChangedFcn = createCallbackFcn(app, @AdjustSliderValueChanged, true);
            app.AdjustSlider.Value = [0 1];
            app.AutoButton = uibutton(app.UIFigure, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.Button_left = uibutton(app.UIFigure, 'push');
            app.Button_left.ButtonPushedFcn = createCallbackFcn(app, @Button_leftPushed, true);
            app.Button_left.Text = '◀';
            app.Button_right = uibutton(app.UIFigure, 'push');
            app.Button_right.ButtonPushedFcn = createCallbackFcn(app, @Button_leftPushed, true);
            app.Button_right.Text = '▶';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0/0';
            app.Panel = uipanel(app.UIFigure);
            app.ReconSizeDropDownLabel = uilabel(app.UIFigure);
            app.ReconSizeDropDownLabel.Text = 'Recon. Size';
            app.ReconSizeDropDown = uidropdown(app.UIFigure);
            app.ReconSizeDropDown.Items = {'128x128', '256x256', '512x512'};
            app.ReconSizeDropDown.ItemsData = {'128', '256', '512'};
            app.ReconSizeDropDown.Value = '256';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cal_registration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end