classdef save_animation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DepthstepEditField         matlab.ui.control.NumericEditField
        DepthstepEditFieldLabel    matlab.ui.control.Label
        DepthendEditField          matlab.ui.control.NumericEditField
        DepthendEditFieldLabel     matlab.ui.control.Label
        DepthstartEditField        matlab.ui.control.NumericEditField
        DepthstartEditFieldLabel   matlab.ui.control.Label
        savetifstackCheckBox       matlab.ui.control.CheckBox
        savemp4CheckBox            matlab.ui.control.CheckBox
        scanMultipleFile           matlab.ui.control.StateButton
        ProessingLabel             matlab.ui.control.Label
        fileSuffixEditField        matlab.ui.control.EditField
        fileSuffixEditFieldLabel   matlab.ui.control.Label
        capturedfpsEditField       matlab.ui.control.NumericEditField
        capturedfpsEditFieldLabel  matlab.ui.control.Label
        MaxButton                  matlab.ui.control.Button
        Time0sLabel                matlab.ui.control.Label
        SaveButton                 matlab.ui.control.Button
        FrameLabel                 matlab.ui.control.Label
        endEditField               matlab.ui.control.NumericEditField
        endEditFieldLabel          matlab.ui.control.Label
        startEditField             matlab.ui.control.NumericEditField
        startEditFieldLabel        matlab.ui.control.Label
        fpsEditField               matlab.ui.control.NumericEditField
        fpsEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Description
        frame_range
        filename
        depth
        n_depth
    end
    
    methods (Access = public)
        
        function init(app,filename, frame_range, depth, n_depth)
            app.frame_range = frame_range;
            app.filename  = filename;
            if nargin>3
                app.depth = depth;
            end
            if nargin>4
                app.n_depth = n_depth;
                app.DepthendEditField.Value = n_depth;
            end
            app.FrameLabel.Text = sprintf('Frame #%d - #%d',frame_range(1),frame_range(2));
            app.startEditField.Limits = [0,inf];
            app.endEditField.Limits = [0,inf];
            
            app.startEditField.Value = max(frame_range(1),app.startEditField.Value);
            app.endEditField.Value = min(frame_range(2),app.endEditField.Value);
            app.capturedfpsEditField.Value = min(frame_range(2)-frame_range(1)+1,app.capturedfpsEditField.Value);
            
            app.startEditField.Limits = frame_range;
            app.endEditField.Limits = frame_range;

            app.ProessingLabel.Text = ['To be processed: ', filename];

            % 使用正则表达式查找数字+hz模式（不区分大小写）
            [matchStart, ~, ~, matches] = regexp(filename, '(\d+)hz', 'start', 'end', 'tokenExtents', 'tokens', 'ignorecase');
            % 检查是否找到匹配
            if ~isempty(matchStart)
                % 提取第一个匹配的数字部分
                capturedFPS = str2double(matches{1}{1});
                % 确保转换有效（不是NaN）
                if ~isnan(capturedFPS)
                    % 更新app.capturedfpsEditField.Value
                    app.capturedfpsEditField.Value = capturedFPS;
                end
            end
            app.MaxButtonPushed(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, filename, frame_range, depth, n_depth)
            app.MainApp = mainapp;
            init(app,filename, frame_range, depth, n_depth);
        end

        % Value changed function: startEditField
        function startEditFieldValueChanged(app, event)
            if app.startEditField.Value>app.endEditField.Value
                app.startEditField.Value = app.endEditField.Value;
            end
            app.Time0sLabel.Text = sprintf('Time: %.1f s', (app.endEditField.Value-app.startEditField.Value+1)/app.fpsEditField.Value);
        end

        % Value changed function: endEditField
        function endEditFieldValueChanged(app, event)
            if app.startEditField.Value>app.endEditField.Value
                app.endEditField.Value = app.startEditField.Value;
            end
            app.Time0sLabel.Text = sprintf('Time: %.1f s', (app.endEditField.Value-app.startEditField.Value+1)/app.fpsEditField.Value);
        end

        % Button pushed function: MaxButton
        function MaxButtonPushed(app, event)
            app.startEditField.Value = app.frame_range(1);
            app.endEditField.Value = app.frame_range(2);
            app.Time0sLabel.Text = sprintf('Time: %.1f s', (app.endEditField.Value-app.startEditField.Value+1)/app.fpsEditField.Value);
            drawnow;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % 获取文件列表
            if app.scanMultipleFile.Value
                fileList = get_items_with_cfg(app.MainApp);
                num_file = length(fileList);
            else
                num_file = 1;
                [filename, frame_range, depth, n_depth] = get_current_item(app.MainApp);
                init(app, filename, frame_range, depth, n_depth);
            end

            % 获取保存格式选项
            save_mp4 = app.savemp4CheckBox.Value; % 默认保存MP4
            save_tiff = app.savetifstackCheckBox.Value; % 保存TIFF堆栈

            % 创建进度条
            progress_bar = waitbar(0, 'Initializing...', 'Name', 'Processing Progress');
            set(gca(progress_bar).Title,'Interpreter','none');
            set(progress_bar, 'Position', [300, 300, 350, 120]);
            % 处理每个文件
            for l = 1:num_file
                if num_file > 1
                    [filename, frame_range] = set_item_with_cfg(app.MainApp, fileList{l});
                    init(app, filename, frame_range);
                    file_info = sprintf('File %d/%d: %s', l, num_file, filename);
                else
                    file_info = sprintf('Processing: %s', filename);
                end
                app.ProessingLabel.Text = file_info;

                % 初始化读取函数
                gen_recon_frame_init(app.MainApp);

                % 计算总帧数和维度
                frame_start = app.startEditField.Value;
                frame_end = app.endEditField.Value;
                num_frames = frame_end - frame_start + 1;

                % 获取第一帧来确定数据维度
                [first_frame,amp] = gen_recon_frame(app.MainApp, frame_start);
                depth_idx = app.DepthstartEditField.Value:app.DepthstepEditField.Value:app.DepthendEditField.Value;
                first_frame = first_frame(:,:,depth_idx);
                [height, width, num_depth] = size(first_frame);

                % 计算总的处理步骤数
                if ~save_mp4
                    total_steps = num_frames;
                else
                    total_steps = num_frames + num_depth * num_frames; % 重建帧 + 生成视频帧
                end
                current_step = 0;

                % 获取保存路径和文件名
                base_filename = [app.filename, app.fileSuffixEditField.Value];
                pathname = app.MainApp.data_path;
                % 创建mat
                data_file = fullfile(pathname, [base_filename,'.mat']);

                % 预先创建4D数组结构
                reconVol_all = zeros(height, width, num_depth, num_frames, 'single'); % single减少内存
                amp_all = zeros(1, num_frames);
                frame_rate = app.capturedfpsEditField.Value;
                pixel_size = (app.MainApp.pixel_size_x+app.MainApp.pixel_size_y)/2;
                depth_size = app.depth/(num_depth-1);
                save(data_file, 'reconVol_all','amp_all','frame_rate','pixel_size','depth_size', '-v7.3');
                matfile_obj = matfile(data_file, 'Writable', true);

                % 逐帧重建并保存
                step_start_time = tic;
                for i = frame_start:frame_end
                    current_step = current_step + 1;

                    % 重建当前帧
                    if i == frame_start
                        reconVol = first_frame;
                    else
                        [reconVol, amp] = gen_recon_frame(app.MainApp, i);
                        reconVol = reconVol(:,:,depth_idx);
                    end

                    % 保存到文件
                    frame_idx = i - frame_start + 1;
                    matfile_obj.reconVol_all(:, :, :, frame_idx) = reconVol;
                    amp_all(frame_idx) = amp;

                    % 更新进度
                    progress = current_step / total_steps;

                    % 估算剩余时间
                    elapsed_time = toc(step_start_time);
                    if current_step > 1
                        estimated_total_time = elapsed_time / progress;
                        remaining_time = estimated_total_time - elapsed_time;
                        time_str = formatTime(remaining_time);
                        status_msg = sprintf('%s\n Reconstructing frame %d/%d\nEstimated time remaining: %s', ...
                            file_info, frame_idx, num_frames, time_str);
                    else
                        status_msg = sprintf('%s\n Reconstructing frame %d/%d', file_info, frame_idx, num_frames);
                    end

                    waitbar(progress, progress_bar, status_msg);
                end
                matfile_obj.amp_all = amp_all;
                
                % 加载完整数据用于后续处理
                data_struct = load(data_file);
                reconVol_all = data_struct.reconVol_all;
                amp_all = amp_all/max(amp_all(:)); 
                disp('Data .mat file saved.')

                % === 优化2: 直接生成图像数据 ===
                dt = 1/app.capturedfpsEditField.Value;

                % 预计算所有图像
                total_video_frames = num_depth * num_frames;

                % 为不同格式创建数据结构
                if save_mp4
                    frames = cell(total_video_frames, 1); % 预分配cell数组
                end

                if save_tiff
                    % 为TIFF保存准备数据
                    tiff_data = zeros(height, width, total_video_frames, 'uint8');
                end

                video_frame_idx = 0;

                if (~save_mp4) && (~save_tiff)
                    continue;
                end

                % 创建隐藏figure用于渲染
                f = figure('Visible', 'off', 'Position', [100 100 800 600]);
                ax = axes(f);
                for j = 1:num_depth
                    for i = 1:num_frames
                        video_frame_idx = video_frame_idx + 1;
                        current_step = current_step + 1;

                        % 显示当前切片
                        img_data = reconVol_all(:, :, j, i) * amp_all(i);

                        % === 优化3: 直接操作图像数据 ===
                        imagesc(ax, img_data);
                        colormap(ax, 'gray');
                        axis(ax, 'image');

                        % 计算显示信息
                        curr_time = i * dt;
                        total_time = num_frames * dt;
                        curr_depth = (j-1)/(num_depth-1) * app.depth - app.depth/2;

                        title(ax, sprintf('Time %.3f/%.2fs | frame %d/%d | depth %d/%d (%.0fμm)', ...
                            curr_time, total_time, i, num_frames, j, num_depth, curr_depth));

                        % 添加比例尺
                        addScaleBar(ax, 100/app.MainApp.pixel_size_x, '100μm', 14, 0.01, app.MainApp.scale_bar_pos);

                        % === 优化4: 更高效的图像捕获 ===
                        if save_mp4
                            % 使用print函数而不是getframe，更稳定
                            temp_img_file = fullfile(tempdir, sprintf('temp_frame_%06d.png', video_frame_idx));
                            print(f, temp_img_file, '-dpng', '-r150');
                            frame_img = imread(temp_img_file);
                            frames{video_frame_idx} = im2frame(frame_img);
                            delete(temp_img_file);
                        end

                        if save_tiff
                            % 直接获取图像数据，归一化到uint8
                            normalized_img = uint8(255 * mat2gray(img_data));
                            tiff_data(:, :, video_frame_idx) = normalized_img;
                        end

                        % 更新进度
                        progress = current_step / total_steps;

                        elapsed_time = toc(step_start_time);
                        if current_step > num_frames
                            estimated_total_time = elapsed_time / progress;
                            remaining_time = estimated_total_time - elapsed_time;
                            time_str = formatTime(remaining_time);
                            status_msg = sprintf('%s\n Generating frame %d/%d (depth %d/%d)\nEstimated time remaining: %s', ...
                                file_info, video_frame_idx, total_video_frames, j, num_depth, time_str);
                        else
                            status_msg = sprintf('%s\n Generating frame %d/%d (depth %d/%d)', ...
                                file_info, video_frame_idx, total_video_frames, j, num_depth);
                        end

                        waitbar(progress, progress_bar, status_msg);
                    end
                end

                close(f);
                

                % === 保存多种格式 ===
                waitbar(0.9, progress_bar, sprintf('%s\n Saving files...', file_info));
                % 1. 保存MP4视频
                if save_mp4
                    waitbar(0.92, progress_bar, sprintf('%s\n Saving MP4...', file_info));
                    idx = 1;
                    for z = 1:num_depth
                        mp4_filename = fullfile(pathname, [base_filename,  sprintf('_%d.mp4',z)]);
                        videoWriter = VideoWriter(mp4_filename, 'MPEG-4');
                        videoWriter.Quality = 95;
                        videoWriter.FrameRate = app.fpsEditField.Value;
                        open(videoWriter);
                        for t = 1:num_frames
                            writeVideo(videoWriter, frames{idx});
                            idx = idx+1;
                        end
                        close(videoWriter);
                    end                    
                end

                % 2. 保存4D TIFF堆栈
                if save_tiff
                    waitbar(0.95, progress_bar, sprintf('%s - Saving 4D TIFF...', file_info));
                    % 重新排列数据为ImageJ兼容的维度顺序 (XYCZT)
                    % reconVol_all是 [height, width, num_depth, time]
                    % 需要转换为 [height, width, channels, depth, time] 对于ImageJ

                    % 保存为多页TIFF，每页是一个time-depth组合
                    for z = 1:num_depth
                        tiff_filename = fullfile(pathname, [base_filename, sprintf('_%d.tif',z)]);
                        
                        for t = 1:num_frames
                            img_slice = uint16(65535 * reconVol_all(:, :, z, t));
                            if t==1
                                imwrite(img_slice, tiff_filename, 'WriteMode', 'overwrite');
                            else
                                imwrite(img_slice, tiff_filename, 'WriteMode', 'append');
                            end
                        end
                    end
                end
            end
            waitbar(1.0, progress_bar, sprintf('%s - Completed!', file_info));
            app.ProessingLabel.Text = 'All files processed successfully!';


            % 关闭进度窗口（延迟2秒）
            pause(2);
            if isvalid(progress_bar)
                close(progress_bar);
            end
        end

        % Value changed function: fpsEditField
        function fpsEditFieldValueChanged(app, event)
            app.Time0sLabel.Text = sprintf('Time: %.1f s', (app.endEditField.Value-app.startEditField.Value+1)/app.fpsEditField.Value);
            drawnow;
        end

        % Value changed function: scanMultipleFile
        function scanMultipleFileValueChanged(app, event)
            value = app.scanMultipleFile.Value;
            if value
                fileList = get_items_with_cfg(app.MainApp);
                msgbox(fileList);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.fpsEditFieldLabel = uilabel(app.UIFigure);
            app.fpsEditFieldLabel.Text = 'fps';
            app.fpsEditField = uieditfield(app.UIFigure, 'numeric');
            app.fpsEditField.ValueChangedFcn = createCallbackFcn(app, @fpsEditFieldValueChanged, true);
            app.fpsEditField.Value = 40;
            app.startEditFieldLabel = uilabel(app.UIFigure);
            app.startEditFieldLabel.Text = 'start';
            app.startEditField = uieditfield(app.UIFigure, 'numeric');
            app.startEditField.ValueChangedFcn = createCallbackFcn(app, @startEditFieldValueChanged, true);
            app.startEditField.Value = 1;
            app.endEditFieldLabel = uilabel(app.UIFigure);
            app.endEditFieldLabel.Text = 'end';
            app.endEditField = uieditfield(app.UIFigure, 'numeric');
            app.endEditField.ValueChangedFcn = createCallbackFcn(app, @endEditFieldValueChanged, true);
            app.endEditField.Value = 1;
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = 'Frame #';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Time0sLabel = uilabel(app.UIFigure);
            app.Time0sLabel.Text = 'Time: 0s';
            app.MaxButton = uibutton(app.UIFigure, 'push');
            app.MaxButton.ButtonPushedFcn = createCallbackFcn(app, @MaxButtonPushed, true);
            app.MaxButton.Text = 'Max';
            app.capturedfpsEditFieldLabel = uilabel(app.UIFigure);
            app.capturedfpsEditFieldLabel.Text = 'captured fps';
            app.capturedfpsEditField = uieditfield(app.UIFigure, 'numeric');
            app.capturedfpsEditField.Value = 600;
            app.fileSuffixEditFieldLabel = uilabel(app.UIFigure);
            app.fileSuffixEditFieldLabel.Text = 'fileSuffix';
            app.fileSuffixEditField = uieditfield(app.UIFigure, 'text');
            app.fileSuffixEditField.Value = '_rl';
            app.ProessingLabel = uilabel(app.UIFigure);
            app.ProessingLabel.Text = 'Proessing: ';
            app.scanMultipleFile = uibutton(app.UIFigure, 'state');
            app.scanMultipleFile.ValueChangedFcn = createCallbackFcn(app, @scanMultipleFileValueChanged, true);
            app.scanMultipleFile.Text = 'scanMultipleFile?';
            app.savemp4CheckBox = uicheckbox(app.UIFigure);
            app.savemp4CheckBox.Text = 'save mp4';
            app.savetifstackCheckBox = uicheckbox(app.UIFigure);
            app.savetifstackCheckBox.Text = 'save tif stack';
            app.DepthstartEditFieldLabel = uilabel(app.UIFigure);
            app.DepthstartEditFieldLabel.Text = 'Depth start';
            app.DepthstartEditField = uieditfield(app.UIFigure, 'numeric');
            app.DepthstartEditField.Value = 1;
            app.DepthendEditFieldLabel = uilabel(app.UIFigure);
            app.DepthendEditFieldLabel.Text = 'Depth end';
            app.DepthendEditField = uieditfield(app.UIFigure, 'numeric');
            app.DepthendEditField.Value = 1;
            app.DepthstepEditFieldLabel = uilabel(app.UIFigure);
            app.DepthstepEditFieldLabel.Text = 'Depth step';
            app.DepthstepEditField = uieditfield(app.UIFigure, 'numeric');
            app.DepthstepEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = save_animation(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end